classdef MajorProject1V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WallConfigurationPanel         matlab.ui.container.Panel
        GridLayout_6                   matlab.ui.container.GridLayout
        Switch_2                       matlab.ui.control.Switch
        WalltoggleLabel                matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        DistancemEditField             matlab.ui.control.NumericEditField
        DistancemEditFieldLabel        matlab.ui.control.Label
        HelpPanel                      matlab.ui.container.Panel
        GridLayout_5                   matlab.ui.container.GridLayout
        InstructionsLabel              matlab.ui.control.Label
        ParameterLabel                 matlab.ui.control.Label
        AdvancedParameterControlPanel  matlab.ui.container.Panel
        GridLayout_4                   matlab.ui.container.GridLayout
        Switch_3                       matlab.ui.control.Switch
        HoldpreviouspathsLabel         matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        Accellerationduetogravityms2EditField  matlab.ui.control.NumericEditField
        Accellerationduetogravityms2EditFieldLabel  matlab.ui.control.Label
        ZaxistoggleLabel               matlab.ui.control.Label
        ProjectilecoefficientofrestitutionEditField  matlab.ui.control.NumericEditField
        ProjectilecoefficientofrestitutionEditFieldLabel  matlab.ui.control.Label
        LaunchHeightmEditField         matlab.ui.control.NumericEditField
        LaunchHeightmEditFieldLabel    matlab.ui.control.Label
        VectorResolutionPanel          matlab.ui.container.Panel
        GridLayout_3                   matlab.ui.container.GridLayout
        ComponentsLabel                matlab.ui.control.Label
        ResultantVectorLabel           matlab.ui.control.Label
        UITable_3                      matlab.ui.control.Table
        ResolvevectorButton            matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        GraphPanel                     matlab.ui.container.Panel
        GridLayout_2                   matlab.ui.container.GridLayout
        RealtimeLamp                   matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        TimesLabel                     matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        VerticalspeedGauge             matlab.ui.control.LinearGauge
        VerticalspeedGaugeLabel        matlab.ui.control.Label
        PlotTrajectoryButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ProjectileSimulatorLabel       matlab.ui.control.Label
        InitialVectorInputPanel        matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        RemovelastvectorButton         matlab.ui.control.Button
        SubmitvectorButton             matlab.ui.control.Button
        AngledegreesEditField          matlab.ui.control.NumericEditField
        AngledegreesEditFieldLabel     matlab.ui.control.Label
        Velocityms1EditField           matlab.ui.control.NumericEditField
        Velocityms1EditFieldLabel      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        velocityArray = []; % initialises array to store initial velocities entered by user
        angleArray = []; % initialises array to store initial angles corrosponding to the velocities entered by user

        speeds = []; % initialises the array for vertical speeds

        xValues = []; % ----------------------------------------------------------------------- make these a matrix where each plot goes to a new row so that all lines can be scrolled at the same time
        yValues = [];

        initialVertical; % initialises global vertical component variable
        initialHorizontal; % initialises global horizontal component variable

        initialHeight = 0; % sets default value for initial height to 0
        accelleration = 9.81; % sets default value for downwards accelleration to the value of accelleration due to gravity on Earth at sea level
        coefficientOfRestitution = 1; % sets the default value of coefficient of restitution to ideal model

        wallPresent = false; % sets the default value for presence of walls to false
        wallDistance = 0; % sets the default distance of the wall from the launch site to 0
        wallHeight = 0; % sets the default height of the wall to 0

        holdPaths = true; % sets the default preference for previous plotted trajectory holding to true
    end
    
    methods (Access = private)
        function resolveVectors(app)
            horizontalComponent = 0; % initialises the horizontal component variable
            verticalComponent = 0; % initialises the vertical component variable

            for i = 1:length(app.velocityArray) % for the number of vectors input by the user, the following code will execute
                horizontalComponent = horizontalComponent + app.velocityArray(i)*sind(90-app.angleArray(i)); % increases/decreases the overall horizontal component variable by the resolved horizontal component of the vector corrosponding to i
                verticalComponent = verticalComponent + app.velocityArray(i)*cosd(90-app.angleArray(i)); % increases/decreases the overall vertical component variable by the resolved vertical component of the vector corrosponding to i
            end % ends the for loop
            
            angle = 90-atand(horizontalComponent/verticalComponent); % calculates the resultant angle
            velocity = (horizontalComponent^2 + verticalComponent^2)^0.5; % calculates the resultant velocity
            
            app.UITable_2.Data = {angle, velocity}; % adds the resultant angle and velocity to the overall vector table
            app.UITable_3.Data = {'Horizontal' horizontalComponent ; 'Vertical' verticalComponent}; % adds the horizontal and vertical components to the component table

            app.initialVertical = verticalComponent; % assigns vertical component to the global variable so that it can be accessed outside the function
            app.initialHorizontal = horizontalComponent; % assigns horizontal component to the global variable so that it can be accessed outside the function
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitvectorButton
        function SubmitvectorButtonPushed(app, event)
            updatedData = [(length(app.velocityArray)+1) app.Velocityms1EditField.Value app.AngledegreesEditField.Value]; % sets user input values as a variable to be appended as a new row in the table
            app.UITable.Data = [app.UITable.Data;updatedData]; % adds the data to the next row of the table

            app.velocityArray(length(app.velocityArray)+1) = app.Velocityms1EditField.Value; % adds the user input to the velocity array to be resolved later
            app.angleArray(length(app.angleArray)+1) = app.AngledegreesEditField.Value; % adds the user input to the velocity array to be resolved later

            app.RemovelastvectorButton.Enable = "on";

            app.Velocityms1EditField.Value = 0; % resets the velocity input field to 0
            app.AngledegreesEditField.Value = 0; % resets the angle input field to 0
        end

        % Button pushed function: ResolvevectorButton
        function ResolvevectorButtonPushed(app, event)
            resolveVectors(app); % calls function to resolve vectors. This is a separate function as it must be called whenever the graph is plot in case the user does not click this button beforehand or adds more vectors after clicking this vector
        end

        % Button pushed function: PlotTrajectoryButton
        function PlotTrajectoryButtonPushed(app, event)
            app.RealtimeLamp.Color = [0, 1, 0];
            if app.holdPaths == false % if the hold paths button is in the off position
                %hold(app.UIAxes, 'off'); %     ---------------------------------------------------------------check if necessary
                cla(app.UIAxes, 'reset') % clears previous paths
            end % ends if statement

            resolveVectors(app); % resolves all vectors in the table
            collision = false; % sets the collision checker to false
            app.xValues = []; % clears the x values array
            app.yValues = []; % clears the y values array
            app.speeds = []; % clears the y speeds array

            yCollisionNotAdded = true; % sets the value of a variable which ensures that an if statement within a for loop only executes once

            % finding initial upwards vertical displacement (s)
            u = app.initialVertical; % sets initial velocity as the vertical component of the resultant vector
            v = 0; % the final velocity should be 0 as this is when the maximum height has been reached
            a = -app.accelleration; % gravity is decellerating the projectile as it travels upwards and therefore it should be a negative accelleration
            s = (-u^2)/(2*a); % calculates the displacement from the start to the maximum height

            
            % finding time for vertical ascent and descent (t1 and t2)
            t1 = (2*s)/(v+u); % calculates the time taken to reach maximum height
            t2 = 2*t1; % calculates the time taken to reach the initial height again

            % finding end velocity at return (v)
            a = app.accelleration; % sets accelleration due to gravity to a positive accelleration
            returnVelocity = (2*a*s)^0.5; % finds the final velocity when the projectile returns to its initial height. This will be the initial velocity of the calculation for the rest of the fall if the projectile was launched from a height
            
            % finding total time of projectile (t3)
            s = app.initialHeight; % sets the displacement to the initial height of the projectile
            u = returnVelocity; % sets the initial velocity to the final velocity of the previous fall

            v = (u^2 + 2*a*s)^0.5; % finds the final velocity when the projectile hits the ground

            t3 = t2 + s/((v+u)/2);  % calculates the total time taken for the projectile to reach the ground
            
            if app.wallPresent % if the user has specified that a wall is present the following code will execute
                tCollision = app.wallDistance/app.initialHorizontal; % calculates the exact time of the collision of the projectile with the wall
                if tCollision <= t1 % if the time of the collision is on the ascent of the projectile the following code will execute
                    yCollision = app.initialVertical*tCollision + 0.5*-app.accelleration*tCollision^2; % calculates the y value at the point of collision
                else % if the projectile is descending the following code will execute
                    yCollision = 0.5*app.accelleration*tCollision^2; % calculates the y value at the point of collision
                end % ends the nested if statement
            end % emds the if statement

            if t3 > 0 % t3 must be greater than 0 or an error will occur
                app.TimeSlider.Limits = [0 t3]; % sets the upper limit of the time slider to the total time of the projectile flight
            end %ends if statement

            % preparing x and y values from data
            time = 0:0.05:t3; % as the animated line function is limited to 20fps, to obtain real time animation, a point should be made at every 20th of a second. To do this the division is set to 1/20 (0.05) and thus each frame has an x and y value.

            for i = 1:length(time) % for the number of frames in the given time, the following code will execute
                if time(i) < t1 % if the time is less than or equal to the time of ascent, the following code will execute 
                    a = -app.accelleration; % accelleration due to gravity is set to negative
                    u = app.initialVertical; % initial velocity is set to the resultant vertical component
                else % if the time is more than or equal the total ascent time
                    a = app.accelleration; % accelleration due to gravity is set to positive
                    u = 0; % initial velocity is set to 0
                end % ends the if statement


                if time(i) <= t1 % if the projectile has not yet returned to the initial height
                    t = time(i); % time is set to the current frame time
                    app.yValues(i) = app.initialHeight + u*t+(a*t^2)/2; % the y value for this frame is calculated and stored
                elseif time(i) <= t2 % if the projectile has begun its descent
                    t = time(i) - t1; % sets the time elapsed since the projectile began its descent
                    app.yValues(i) = max(app.yValues) - (a*t^2)/2; % the y value for this frame is calculated and stored
                elseif time(i) > t2 % if time is more than the total ascent and descent time, the projectile is below the initial height and the following code will execute
                    t = time(i) - t2; % sets the time elapsed since the projectile height superceeded the initial height
                    u = returnVelocity; %initial velocity is set to the final velocity when the projectile reaches initial height
                    app.yValues(i) = app.initialHeight - (u*t+(a*t^2)/2); % the y value for this frame is calculated and stored
                end % ends the if statement

                x = app.initialHorizontal*time(i); % calculates the distance travelled by the projectile at time t


                if app.wallPresent & x >= app.wallDistance & app.xValues(i-1) <= app.wallDistance & app.yValues(i) <= app.wallHeight | collision % if the projectile is colliding or has collided with the wall
                    collision = true; % sets the collision variable to true so that when the loop returns to the if statement it will continue calculating x values in to correct direction
                    app.xValues(i) = app.wallDistance - (x - app.wallDistance); % calculates and stores the x value for this frame
                else % if no collision has occurred, the following code will execute
                    app.xValues(i) = x; % the x value is stored
                end % ends the if statement
                
                if collision && yCollisionNotAdded % if a collision has occured and this code has not yet been executed, the following code will execute
                    app.yValues(i) = yCollision; % sets the current iteration of the y value to the y value when the projectile collides with the wall
                    app.xValues(i) = app.wallDistance; % sets the current iteration of the x value to the distance of the wall from the projectile
                    yCollisionNotAdded = false; % sets a variable to ensure the code in this if statement only executes once
                end % ends the if statement
                

                if i >=2 && time(i) <= t1 % if 2 or more iterations have occured and time is less than the time of the maximum height of the trajectory, the following code will execute
                    app.speeds(i) = (app.yValues(i) - app.yValues(i-1))/0.05; % calculates the vertical speed at the current time value
                elseif i >=2 && time(i) > t1 % if 2 or more iterations have occured and time is more than the time of the maximum height of the trajectory, the following code will execute
                    app.speeds(i) = (app.yValues(i-1) - app.yValues(i))/0.05; % calculates the vertical speed at the current time value
                else % if less than 2 iterations have occurred, the following code will execute
                    app.speeds(i) = app.initialVertical; % sets the speed at the first iteration to the initial vertical component of velocity
                end % ends the if statement

            end % ends the for loop

            if app.yValues(end) ~= 0 % calculates the final x value when the projectile hits the ground so that it is plotted (as this may not always be on a 1/20th of a second time value)
                    app.yValues(length(app.yValues)+1) = 0; % final y value is 0
                    app.speeds(length(app.speeds)+1) = (app.yValues(length(app.yValues)-1))/(t3-time(end)); % calculates the final speed (the time between the last frame and the time the ball hits the ground is not constant so t3 - the final t value is used to find the time. as the final value is 0, the difference in heights is simply the final frame's y value.
                    if collision % if the projectile is travelling in the opposite direction, the x value should be negative
                        app.xValues(length(app.xValues)+1) = app.xValues(end) - app.speeds(end)*(t3-time(end)); % sets x value to the negative distance
                    else % if no collision has occured
                        app.xValues(length(app.xValues)+1) = app.xValues(end) + app.speeds(end)*(t3-time(end)); % sets x value to final distance
                    end % ends if statement
            end % ends if statement
            
            if max(app.speeds) ~= 0 % if the maximum speed is above 0 ms^-1 the following code will execute
                app.VerticalspeedGauge.Limits = [0 max(app.speeds)]; % sets the range of values on the vertical speed gauge to 0 to the max speed
            end % ends the if statement


            app.TimeSlider.MinorTicks = [1:0.05:t3 t3]; % sets the slider markers to 0.05 intervals up to t3
            
            
            hold(app.UIAxes, 'on'); % turns the graph hold function on so previous plots are not overwritten

            if max(app.yValues) < app.wallHeight && app.wallHeight >= max(app.xValues) - min(app.xValues) % if the wall height is greater than the maximum height of the trajectory, the Y axis should scale to fit the wall
                app.UIAxes.YLim = [0 app.wallHeight]; % sets the y axis limit to 0 to the height of the wall
                app.UIAxes.XLim = [min(app.xValues) app.wallHeight];
            elseif max(app.yValues) >= max(app.xValues) - min(app.xValues) % if max height is greater than the x displacement, the following code will execute
                app.UIAxes.YLim = [0 max(app.yValues)]; % sets the y axis limit to 0 to the maximum y value of the trajectory
                app.UIAxes.XLim = [min(app.xValues) max(app.yValues)];
            else
                app.UIAxes.YLim = [0 max(app.xValues)-min(app.xValues)]; 
                app.UIAxes.XLim = [min(app.xValues) max(app.xValues)];
            end % ends the if statement

            %app.UIAxes.XLim = [min(app.xValues) max(app.xValues)]; % sets the limits of the x axis plot so that the path fits perfectly

            
            plot(app.UIAxes, app.xValues, app.yValues, 'color', 'r', 'LineStyle',':'); % plots the trajectory path as a dotted line
            ball = plot(app.UIAxes, 0, app.initialHeight, 'color', 'k','marker', 'o'); % sets ball as a plot and plots its initial position at 0, initial height
            pathTravelled = plot(app.UIAxes, 0, app.initialHeight, 'color', 'm'); % plots the initial point of the path 

            if app.wallPresent == true % if a wall is present the following code will execute
                plot(app.UIAxes, linspace(app.wallDistance,app.wallDistance), linspace(0,app.wallHeight), 'color', 'k', 'LineWidth', 5); % plots the wall on the graph
            end % ends the if statement

            for i = 1:length(app.xValues) % -------------------------------------------------------------- this could be optimised
                delete(ball) % deletes the previously drawn ball
                delete(pathTravelled) % deletes the previously drawn travelled path
                pathTravelled = plot(app.UIAxes, app.xValues(1:i), app.yValues(1:i), 'color', 'm'); % plots the new path travelled
                ball = plot(app.UIAxes, app.xValues(i), app.yValues(i), 'color', 'k','marker', 'o'); % plots the projectile's new position
                %quiver(current x, current y, magnitude x, magnitude y, 'color', 'k') shows velocity vector

                app.VerticalspeedGauge.Value = app.speeds(i); % updates the speed guage speed
                pause(0.05) % waits for 0.05 seconds so that the animation occurs in real time
            end

            app.RealtimeLamp.Color = [1, 0, 0];


        end

        % Value changed function: LaunchHeightmEditField
        function LaunchHeightmEditFieldValueChanged(app, event)
            value = app.LaunchHeightmEditField.Value;
            app.initialHeight = double(value);
        end

        % Value changed function: Accellerationduetogravityms2EditField
        function Accellerationduetogravityms2EditFieldValueChanged(app, event)
            value = app.Accellerationduetogravityms2EditField.Value;
            app.accelleration = double(value);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.wallPresent = ~app.wallPresent;
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            value = app.DistancemEditField.Value;
            app.wallDistance = double(value);
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;
            app.wallHeight = double(value);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            app.holdPaths = ~app.holdPaths;
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = double(event.Value); % sets a variable to the value of the time slider
            app.RealtimeLamp.Color = [1, 1, 0];

            % this needs to be optimised
            cla(app.UIAxes); % clears the axes
            plot(app.UIAxes, app.xValues(1:1:ceil(changingValue/0.05)+1), app.yValues(1:1:ceil(changingValue/0.05)+1), 'color', 'm'); % plots the previous trajectory of the projectile at the time selected
            plot(app.UIAxes, app.xValues(ceil(changingValue/0.05)+1), app.yValues(ceil(changingValue/0.05)+1), 'color', 'k','marker', 'o'); % plots the projectile position at the time selected 
            app.VerticalspeedGauge.Value = app.speeds(ceil(changingValue/0.05)+1); % updates the speed guage with the speed at the time selected

        end

        % Button pushed function: RemovelastvectorButton
        function RemovelastvectorButtonPushed(app, event)
            app.velocityArray(end) = []; % removes last vector velocity from the array 
            app.angleArray(end) = []; % removes last vector angle from the array 
            app.UITable.Data(end-1:end) = []; % removes the stored data of the last vector from the table data array
            app.UITable.Data = [app.UITable.Data(1:end-2)]; % updates the table

            if isempty(app.velocityArray) % if all vectors have been removed the following code will execute
               app.RemovelastvectorButton.Enable = "off"; % disables the button as there are no more elements to remove
               app.UITable_2.Data = []; % clears the table for the resultant vector
               app.UITable_3.Data = []; % clears the table of components
            else
                app.resolveVectors(); % resolves the vectors in the updated list and lists components in the components table
            end % ends the if statement
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.RealtimeLamp.Color = [1, 0, 0];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitialVectorInputPanel = uipanel(app.UIFigure);
            app.InitialVectorInputPanel.Title = 'Initial Vector Input Panel';
            app.GridLayout = uigridlayout(app.InitialVectorInputPanel);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Vector no.'; 'Velocity'; 'Angle'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.Velocityms1EditFieldLabel = uilabel(app.GridLayout);
            app.Velocityms1EditFieldLabel.Text = 'Velocity / ms^-1';
            app.Velocityms1EditField = uieditfield(app.GridLayout, 'numeric');
            app.AngledegreesEditFieldLabel = uilabel(app.GridLayout);
            app.AngledegreesEditFieldLabel.Text = 'Angle / degrees';
            app.AngledegreesEditField = uieditfield(app.GridLayout, 'numeric');
            app.SubmitvectorButton = uibutton(app.GridLayout, 'push');
            app.SubmitvectorButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitvectorButtonPushed, true);
            app.SubmitvectorButton.Text = 'Submit vector';
            app.RemovelastvectorButton = uibutton(app.GridLayout, 'push');
            app.RemovelastvectorButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastvectorButtonPushed, true);
            app.RemovelastvectorButton.Enable = 'off';
            app.RemovelastvectorButton.Text = 'Remove last vector';
            app.ProjectileSimulatorLabel = uilabel(app.UIFigure);
            app.ProjectileSimulatorLabel.Text = 'Projectile Simulator';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.GridLayout_2 = uigridlayout(app.GraphPanel);
            app.UIAxes = uiaxes(app.GridLayout_2);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'X displacement / m')
            ylabel(app.UIAxes, 'Y displacement / m')
            zlabel(app.UIAxes, 'Z')
            app.PlotTrajectoryButton = uibutton(app.GridLayout_2, 'push');
            app.PlotTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonPushed, true);
            app.PlotTrajectoryButton.Text = 'Plot Trajectory';
            app.VerticalspeedGaugeLabel = uilabel(app.GridLayout_2);
            app.VerticalspeedGaugeLabel.Text = 'Vertical speed';
            app.VerticalspeedGauge = uigauge(app.GridLayout_2, 'linear');
            app.VerticalspeedGauge.Limits = [0 30];
            app.TimeSlider = uislider(app.GridLayout_2);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [0 0.8 1.6 2.4 3.2 4 4.8 5.6 6.4 7.2 8 8.8 9.6 10.4 11.2 12 12.8 13.6 14.4 15.2 16 16.8 17.6 18.4 19.2 20 20.8 21.6 22.4 23.2 24 24.8 25.6 26.4 27.2 28 28.8 29.6 30.4 31.2 32 32.8 33.6 34.4 35.2 36 36.8 37.6 38.4 39.2 40 40.8 41.6 42.4 43.2 44 44.8 45.6 46.4 47.2 48 48.8 49.6 50.4 51.2 52 52.8 53.6 54.4 55.2 56 56.8 57.6 58.4 59.2 60 60.8 61.6 62.4 63.2 64 64.8 65.6 66.4 67.2 68 68.8 69.6 70.4 71.2 72 72.8 73.6 74.4 75.2 76 76.8 77.6 78.4 79.2 80 80.8 81.6 82.4 83.2 84 84.8 85.6 86.4 87.2 88 88.8 89.6 90.4 91.2 92 92.8 93.6 94.4 95.2 96 96.8 97.6 98.4 99.2 100];
            app.TimesLabel = uilabel(app.GridLayout_2);
            app.TimesLabel.Text = 'Time / s';
            app.Label = uilabel(app.GridLayout_2);
            app.Label.Text = 'Real time';
            app.RealtimeLamp = uilamp(app.GridLayout_2);
            app.VectorResolutionPanel = uipanel(app.UIFigure);
            app.VectorResolutionPanel.Title = 'Vector Resolution Panel';
            app.GridLayout_3 = uigridlayout(app.VectorResolutionPanel);
            app.UITable_2 = uitable(app.GridLayout_3);
            app.UITable_2.ColumnName = {'Angle'; 'Velocity'};
            app.UITable_2.RowName = {};
            app.UITable_2.Multiselect = 'off';
            app.ResolvevectorButton = uibutton(app.GridLayout_3, 'push');
            app.ResolvevectorButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvevectorButtonPushed, true);
            app.ResolvevectorButton.Text = 'Resolve vector';
            app.UITable_3 = uitable(app.GridLayout_3);
            app.UITable_3.ColumnName = {'Direction'; 'Velocity'};
            app.UITable_3.RowName = {};
            app.UITable_3.Multiselect = 'off';
            app.ResultantVectorLabel = uilabel(app.GridLayout_3);
            app.ResultantVectorLabel.Text = 'Resultant Vector';
            app.ComponentsLabel = uilabel(app.GridLayout_3);
            app.ComponentsLabel.Text = 'Components';
            app.AdvancedParameterControlPanel = uipanel(app.UIFigure);
            app.AdvancedParameterControlPanel.Title = 'Advanced Parameter Control Panel';
            app.GridLayout_4 = uigridlayout(app.AdvancedParameterControlPanel);
            app.LaunchHeightmEditFieldLabel = uilabel(app.GridLayout_4);
            app.LaunchHeightmEditFieldLabel.Text = 'Launch Height / m';
            app.LaunchHeightmEditField = uieditfield(app.GridLayout_4, 'numeric');
            app.LaunchHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @LaunchHeightmEditFieldValueChanged, true);
            app.ProjectilecoefficientofrestitutionEditFieldLabel = uilabel(app.GridLayout_4);
            app.ProjectilecoefficientofrestitutionEditFieldLabel.Enable = 'off';
            app.ProjectilecoefficientofrestitutionEditFieldLabel.Text = 'Projectile coefficient of restitution';
            app.ProjectilecoefficientofrestitutionEditField = uieditfield(app.GridLayout_4, 'numeric');
            app.ProjectilecoefficientofrestitutionEditField.Enable = 'off';
            app.ProjectilecoefficientofrestitutionEditField.Value = 1;
            app.ZaxistoggleLabel = uilabel(app.GridLayout_4);
            app.ZaxistoggleLabel.Text = 'Z axis toggle';
            app.Accellerationduetogravityms2EditFieldLabel = uilabel(app.GridLayout_4);
            app.Accellerationduetogravityms2EditFieldLabel.Text = 'Accelleration due to gravity / ms^-2';
            app.Accellerationduetogravityms2EditField = uieditfield(app.GridLayout_4, 'numeric');
            app.Accellerationduetogravityms2EditField.ValueChangedFcn = createCallbackFcn(app, @Accellerationduetogravityms2EditFieldValueChanged, true);
            app.Accellerationduetogravityms2EditField.Value = 9.81;
            app.Switch = uiswitch(app.GridLayout_4, 'slider');
            app.Switch.Enable = 'off';
            app.HoldpreviouspathsLabel = uilabel(app.GridLayout_4);
            app.HoldpreviouspathsLabel.Text = 'Hold previous paths';
            app.Switch_3 = uiswitch(app.GridLayout_4, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.HelpPanel = uipanel(app.UIFigure);
            app.HelpPanel.Title = 'Help Panel';
            app.GridLayout_5 = uigridlayout(app.HelpPanel);
            app.ParameterLabel = uilabel(app.GridLayout_5);
            app.ParameterLabel.Text = 'Parameter';
            app.InstructionsLabel = uilabel(app.GridLayout_5);
            app.InstructionsLabel.Text = 'Instructions';
            app.WallConfigurationPanel = uipanel(app.UIFigure);
            app.WallConfigurationPanel.Title = 'Wall Configuration Panel';
            app.GridLayout_6 = uigridlayout(app.WallConfigurationPanel);
            app.DistancemEditFieldLabel = uilabel(app.GridLayout_6);
            app.DistancemEditFieldLabel.Text = 'Distance / m';
            app.DistancemEditField = uieditfield(app.GridLayout_6, 'numeric');
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.HeightmEditFieldLabel = uilabel(app.GridLayout_6);
            app.HeightmEditFieldLabel.Text = 'Height / m';
            app.HeightmEditField = uieditfield(app.GridLayout_6, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.WalltoggleLabel = uilabel(app.GridLayout_6);
            app.WalltoggleLabel.Text = 'Wall toggle';
            app.Switch_2 = uiswitch(app.GridLayout_6, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MajorProject1V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end