classdef MajorProject1V9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThemePanel                      matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        BrightnessLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        ThemeDropDown                   matlab.ui.control.DropDown
        HelpPanel                       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        DescriptionLabel                matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        WallConfigurationPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        HeightmLabel                    matlab.ui.control.Label
        DistancemLabel                  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        WalltoggleLabel                 matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        DistancemEditField              matlab.ui.control.NumericEditField
        AdvancedParameterControlPanel   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        DownwardAccellerationLabel      matlab.ui.control.Label
        LaunchHeightmLabel              matlab.ui.control.Label
        HoldHelp                        matlab.ui.control.Button
        ZAxisHelp                       matlab.ui.control.Button
        AccellerationHelp               matlab.ui.control.Button
        RestitutionHelp                 matlab.ui.control.Button
        LaunchHeightHelp                matlab.ui.control.Button
        CoefficientofrestitutionLabel   matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        HoldpreviouspathsLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        DownwardAccellerationEditField  matlab.ui.control.NumericEditField
        DVectorToggleLabel              matlab.ui.control.Label
        ProjectilecoefficientofrestitutionEditField  matlab.ui.control.NumericEditField
        LaunchHeightmEditField          matlab.ui.control.NumericEditField
        VectorResolutionPanel           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ComponentsLabel                 matlab.ui.control.Label
        ResultantVectorLabel            matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        ResolvevectorButton             matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        GraphPanel                      matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ViewPanel                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        View1Button                     matlab.ui.control.StateButton
        View2Button                     matlab.ui.control.StateButton
        View3Button                     matlab.ui.control.StateButton
        TimesPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TimeHelp                        matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        CurrentTimesLabel               matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        VerticalSpeedPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        VerticalSpeedHelp               matlab.ui.control.Button
        Verticalspeedms1Gauge           matlab.ui.control.LinearGauge
        RealTimePanel                   matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        RealTimeHelp                    matlab.ui.control.Button
        RealtimeLamp                    matlab.ui.control.Lamp
        PlotTrajectoryButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ProjectileSimulatorLabel        matlab.ui.control.Label
        InitialVectorInputPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        HorizontalAngledegreesLabel     matlab.ui.control.Label
        VerticalAngledegreesLabel       matlab.ui.control.Label
        Velocityms1Label                matlab.ui.control.Label
        RemovelastvectorButton          matlab.ui.control.Button
        SubmitvectorButton              matlab.ui.control.Button
        HorizontalAngledegreesEditField  matlab.ui.control.NumericEditField
        UpwardsAngledegreesEditField    matlab.ui.control.NumericEditField
        Velocityms1EditField            matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        magnitudeArray = []; % initialises array to store initial magnitudes entered by user
        verticalAngleArray = []; % initialises array to store initial angles corrosponding to the velocities entered by user
        horizontalAngleArray = [];

        speeds = []; % initialises the array for vertical speeds

        xValues = []; % length array
        yValues = []; % depth array, using z as height for 2d plots as the 3d plot uses z for the upward direction
        zValues = []; % height array

        initialVertical = 0; % initialises global vertical component variable
        initialForward = 0; % initialises global horizontal component variable
        initialHorizontal = 0;

        initialHeight = 0; % sets default value for initial height to 0
        accelleration = 9.81; % sets default value for downwards accelleration to the value of accelleration due to gravity on Earth at sea level
        coefficientOfRestitution = 1; % sets the default value of coefficient of restitution to ideal model

        wallPresent = 0; % sets the default value for presence of walls to false
        wallDistance = 0; % sets the default distance of the wall from the launch site to 0
        wallHeight = 10; % sets the default height of the wall to 0

        holdPaths = true; % sets the default preference for previous plotted trajectory holding to true

        brightness = 0.075;
    end
    
    methods (Access = private)
        function resolveVectors(app)

            verticalComponent = 0; % initialises the vertical component variable
            intermediate = 0; % initialises the intermediate component variable
            horizontalComponent = 0; % initialises the horizontal component variable

            for i = 1:length(app.magnitudeArray) % for the number of vectors input by the user, the following code will execute
                verticalComponent = verticalComponent + app.magnitudeArray(i)*sind(app.verticalAngleArray(i)); % increases/decreases the overall horizontal component variable by the resolved horizontal component of the vector corrosponding to i
                if app.verticalAngleArray(i) ~= 90 && app.verticalAngleArray(i) ~= 0
                    intermediate = intermediate + (app.magnitudeArray(i)*sind(app.verticalAngleArray(i)))/tand(90-app.verticalAngleArray(i)); % increases/decreases the overall vertical component variable by the resolved vertical component of the vector corrosponding to i
                elseif app.verticalAngleArray(i) == 0
                    intermediate = intermediate + app.magnitudeArray(i);
                end
            end % ends the for loop

            for i = 1:length(app.horizontalAngleArray)
                if app.horizontalAngleArray(i) ~= 0 
                    horizontalComponent = horizontalComponent + intermediate*sind(app.horizontalAngleArray(i));
                end
            end
            
            if horizontalComponent ~= 0
                forwardComponent = (intermediate^2-horizontalComponent^2)^0.5;
                horizontalAngle = asind(horizontalComponent/intermediate);
            else
                forwardComponent = intermediate;
                horizontalAngle = 0;
            end
            
            verticalAngle = atand(verticalComponent/intermediate); % calculates the resultant angle
            if isinf(verticalAngle)
                verticalAngle = 90;
            elseif isnan(verticalAngle)
                verticalAngle = 0;
            elseif verticalAngle ~= 0 && verticalAngle ~= 90
                verticalAngle = 90 - verticalAngle;
            end

            if verticalAngle == 0
                if horizontalComponent ~= 0 && forwardComponent == 0
                    magnitude = horizontalComponent;
                elseif horizontalComponent == 0 && forwardComponent ~= 0
                    magnitude = forwardComponent;
                elseif horizontalComponent ~= 0 && forwardComponent ~= 0
                    magnitude = (horizontalComponent^2+forwardComponent^2)^0.5;
                elseif horizontalComponent == 0 && forwardComponent == 0
                    magnitude = 0;
                end
            elseif verticalAngle == 90
                magnitude = verticalComponent;
            else
                magnitude = verticalComponent/sind(verticalAngle);
            end
            
            app.UITable_2.Data = {magnitude, verticalAngle, horizontalAngle}; % adds the resultant angle and velocity to the overall vector table
            app.UITable_3.Data = {'Forward' forwardComponent ; 'Vertical' verticalComponent ; 'Horizontal' horizontalComponent}; % adds the horizontal and vertical components to the component table

            app.initialVertical = verticalComponent; % assigns vertical component to the global variable so that it can be accessed outside the function
            app.initialForward = forwardComponent; % assigns horizontal component to the global variable so that it can be accessed outside the function
            app.initialHorizontal = horizontalComponent;
            
        end

        function plotLine(app, changingValue)
            cla(app.UIAxes);
            app.plotWall();
            ball = animatedline('Parent', app.UIAxes, 'Marker', 'o');
            pathTravelled = animatedline('Parent', app.UIAxes, 'Color','m');
            clearpoints(ball);
            clearpoints(pathTravelled);
            addpoints(pathTravelled, app.xValues(1:1:ceil(changingValue/0.05)+1), app.yValues(1:1:ceil(changingValue/0.05)+1), app.zValues(1:1:ceil(changingValue/0.05)+1));  % updates projectile position
            addpoints(ball, app.xValues(ceil(changingValue/0.05)+1), app.yValues(ceil(changingValue/0.05)+1), app.zValues(ceil(changingValue/0.05)+1)); % updates the projectile path travelled
            app.Verticalspeedms1Gauge.Value = app.speeds(ceil(changingValue/0.05)+1); % updates the speed guage with the speed at the time selected
            drawnow nocallbacks limitrate; % plots the updated positions
        end

        function plotWall(app)
            if app.wallPresent == true % if a wall is present the following code will execute
                if ~isempty(app.yValues) && min(app.yValues) ~= max(app.yValues)
                    x = app.wallDistance;
                    patch(app.UIAxes, [x x x x x], [min(app.yValues) max(app.yValues) max(app.yValues) min(app.yValues) min(app.yValues)], [0 0 app.wallHeight app.wallHeight 0], 'k');
                else
                    plot3(app.UIAxes, linspace(app.wallDistance,app.wallDistance), linspace(0,0), linspace(0,app.wallHeight), 'color', 'k', 'LineWidth', 5); % plots the wall on the graph
                end
            end % ends the if statement
        end

        function axisScaler(app)
            if max(app.zValues) < app.wallHeight && app.wallHeight >= max(app.xValues) - min(app.xValues) % if the wall height is greater than the maximum height of the trajectory, the Y axis should scale to fit the wall
                app.UIAxes.ZLim = [0 app.wallHeight]; % sets the z axis limit to 0 to the height of the wall
                app.UIAxes.XLim = [min(app.xValues) app.wallHeight];
            elseif max(app.zValues) >= max(app.xValues) - min(app.xValues) % if max height is greater than the x displacement, the following code will execute
                app.UIAxes.ZLim = [0 max(app.zValues)]; % sets the z axis limit to 0 to the maximum y value of the trajectory
                app.UIAxes.XLim = [min(app.xValues) max(app.zValues)];
            else
                app.UIAxes.ZLim = [0 max(app.xValues)-min(app.xValues)]; 
                app.UIAxes.XLim = [min(app.xValues) max(app.xValues)];
            end % ends the if statement
        end

        function brightnessControl(app)
            app.GridLayout.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout2.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout3.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout4.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout5.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout6.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout7.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout8.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout9.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout10.BackgroundColor = [app.brightness, app.brightness, app.brightness];
            app.GridLayout11.BackgroundColor = [app.brightness, app.brightness, app.brightness];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitvectorButton
        function SubmitvectorButtonPushed(app, event)
            if app.Velocityms1EditField.Value > 0
                updatedData = [(length(app.magnitudeArray)+1) app.Velocityms1EditField.Value app.UpwardsAngledegreesEditField.Value app.HorizontalAngledegreesEditField.Value]; % sets user input values as a variable to be appended as a new row in the table
                app.UITable.Data = [app.UITable.Data;updatedData]; % adds the data to the next row of the table
    
                app.magnitudeArray(length(app.magnitudeArray)+1) = app.Velocityms1EditField.Value; % adds the user input to the velocity array to be resolved later
                app.verticalAngleArray(length(app.verticalAngleArray)+1) = app.UpwardsAngledegreesEditField.Value; % adds the user input to the velocity array to be resolved later
                app.horizontalAngleArray(length(app.verticalAngleArray)+1) = app.HorizontalAngledegreesEditField.Value;
    
                app.RemovelastvectorButton.Enable = "on";
                app.PlotTrajectoryButton.Enable = "on";
    
                app.Velocityms1EditField.Value = 0; % resets the velocity input field to 0
                app.UpwardsAngledegreesEditField.Value = 0; % resets the angle input field to 0
                app.HorizontalAngledegreesEditField.Value = 0; % resets the angle input field to 0
            else
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The velocity should be greater than 0.';
            end
        end

        % Button pushed function: ResolvevectorButton
        function ResolvevectorButtonPushed(app, event)
            resolveVectors(app); % calls function to resolve vectors. This is a separate function as it must be called whenever the graph is plot in case the user does not click this button beforehand or adds more vectors after clicking this vector
        end

        % Button pushed function: PlotTrajectoryButton
        function PlotTrajectoryButtonPushed(app, event)
            
            resolveVectors(app); % resolves all vectors in the table
            if app.initialVertical ~= 0 || app.initialHeight ~= 0
                app.RealtimeLamp.Color = [0, 1, 0]; % sets the real time indication lamp colour to green
                if app.holdPaths == false % if the hold paths button is in the off position
                    cla(app.UIAxes, 'reset') % clears previous paths
                end % ends if statement
                
                
                collision = false; % sets the collision checker to false
                xPostCollision = 0;
                app.speeds = []; % clears the y speeds array
                app.xValues = [];
                app.yValues = [];
                app.zValues = [];
    
                zCollisionNotAdded = true; % sets the value of a variable which ensures that an if statement within a for loop only executes once
    
                % finding initial upwards vertical displacement (s)
                u = app.initialVertical; % sets initial velocity as the vertical component of the resultant vector
                v = 0; % the final velocity should be 0 as this is when the maximum height has been reached
                a = -app.accelleration; % gravity is decellerating the projectile as it travels upwards and therefore it should be a negative accelleration
                s = (-u^2)/(2*a); % calculates the displacement from the start to the maximum height
    
                
                % finding time for vertical ascent and descent (t1 and t2)
                t1 = (2*s)/(v+u); % calculates the time taken to reach maximum height
                t2 = 2*t1; % calculates the time taken to reach the initial height again
    
                % finding end velocity at return (v)
                a = app.accelleration; % sets accelleration due to gravity to a positive accelleration
                returnVelocity = (2*a*s)^0.5; % finds the final velocity when the projectile returns to its initial height. This will be the initial velocity of the calculation for the rest of the fall if the projectile was launched from a height
                
                % finding total time of projectile (t3)
                s = app.initialHeight; % sets the displacement to the initial height of the projectile
                u = returnVelocity; % sets the initial velocity to the final velocity of the previous fall
    
                if app.initialVertical == 0
                    u = 0;
                    t1 = 0;
                    t2 = 0;
                end

                v = (u^2 + 2*a*s)^0.5; % finds the final velocity when the projectile hits the ground

                
                t3 = t2 + s/((v+u)/2);  % calculates the total time taken for the projectile to reach the ground

                if app.wallPresent % if the user has specified that a wall is present the following code will execute
                    tCollision = app.wallDistance/app.initialForward; % calculates the exact time of the collision of the projectile with the wall
                    if tCollision <= t1 % if the time of the collision is on the ascent of the projectile the following code will execute
                        zCollision = app.initialVertical*tCollision + 0.5*-app.accelleration*tCollision^2; % calculates the y value at the point of collision
                    else % if the projectile is descending the following code will execute
                        zCollision = (app.initialVertical*t1+0.5*(-app.accelleration)*t1^2)-(0.5*app.accelleration*(tCollision-t1)^2);
                    end % ends the nested if statement
                end % emds the if statement
    
                if t3 > 0 % t3 must be greater than 0 or an error will occur
                    app.TimeSlider.Limits = [0 t3]; % sets the upper limit of the time slider to the total time of the projectile flight
                end %ends if statement

                % preparing x and y values from data
                time = 0:0.05:t3; % as the animated line function is limited to 20fps, to obtain real time animation, a point should be made at every 20th of a second. To do this the division is set to 1/20 (0.05) and thus each frame has an x and y value.
    
                for i = 1:length(time) % for the number of frames in the given time, the following code will execute
                    if time(i) < t1 % if the time is less than or equal to the time of ascent, the following code will execute 
                        a = -app.accelleration; % accelleration due to gravity is set to negative
                        u = app.initialVertical; % initial velocity is set to the resultant vertical component
                    else % if the time is more than or equal the total ascent time
                        a = app.accelleration; % accelleration due to gravity is set to positive
                        u = 0; % initial velocity is set to 0
                    end % ends the if statement
    
                    
                    if time(i) <= t1 % if the projectile has not yet returned to the initial height
                        t = time(i); % time is set to the current frame time
                        app.zValues(i) = app.initialHeight + u*t+(a*t^2)/2; % the y value for this frame is calculated and stored
                    elseif time(i) <= t2 % if the projectile has begun its descent
                        t = time(i) - t1; % sets the time elapsed since the projectile began its descent
                        app.zValues(i) = max(app.zValues) - (a*t^2)/2; % the y value for this frame is calculated and stored
                    elseif time(i) > t2 % if time is more than the total ascent and descent time, the projectile is below the initial height and the following code will execute
                        t = time(i) - t2; % sets the time elapsed since the projectile height superceeded the initial height
                        u = returnVelocity; %initial velocity is set to the final velocity when the projectile reaches initial height
                        app.zValues(i) = app.initialHeight - (u*t+(a*t^2)/2); % the y value for this frame is calculated and stored
                    end % ends the if statement
                    
                    if ~collision
                        x = app.initialForward*time(i); % calculates the distance travelled by the projectile at time t
                    else
                        xPostCollision = -app.initialForward*app.coefficientOfRestitution*(time(i)-tCollision);
                    end
                    

                    %below line used single and/or check if still works
                    if app.wallPresent && x >= app.wallDistance && app.xValues(i-1) <= app.wallDistance && app.zValues(i) <= app.wallHeight || collision % if the projectile is colliding or has collided with the wall
                        collision = true; % sets the collision variable to true so that when the loop returns to the if statement it will continue calculating x values in to correct direction
                        app.xValues(i) = app.wallDistance + xPostCollision;
                    else % if no collision has occurred, the following code will execute
                        app.xValues(i) = x; % the x value is stored
                    end % ends the if statement
                    
                    if collision && zCollisionNotAdded % if a collision has occured and this code has not yet been executed, the following code will execute
                        app.zValues(i) = zCollision; % sets the current iteration of the y value to the y value when the projectile collides with the wall
                        app.xValues(i) = app.wallDistance; % sets the current iteration of the x value to the distance of the wall from the projectile
                        zCollisionNotAdded = false; % sets a variable to ensure the code in this if statement only executes once
                    end % ends the if statement
                    
                    if i >=2 && time(i) <= t1 % if 2 or more iterations have occured and time is less than the time of the maximum height of the trajectory, the following code will execute
                        app.speeds(i) = (app.zValues(i) - app.zValues(i-1))/0.05; % calculates the vertical speed at the current time value
                    elseif i >=2 && time(i) > t1 % if 2 or more iterations have occured and time is more than the time of the maximum height of the trajectory, the following code will execute
                        app.speeds(i) = (app.zValues(i-1) - app.zValues(i))/0.05; % calculates the vertical speed at the current time value
                    else % if less than 2 iterations have occurred, the following code will execute
                        app.speeds(i) = app.initialVertical; % sets the speed at the first iteration to the initial vertical component of velocity
                    end % ends the if statement
    
                    app.yValues(i) = app.initialHorizontal*time(i);
                end % ends the for loop
    
                if app.zValues(end) ~= 0 % calculates the final x value when the projectile hits the ground so that it is plotted (as this may not always be on a 1/20th of a second time value)
                        app.zValues(length(app.zValues)+1) = 0; % final y value is 0
                        app.speeds(length(app.speeds)+1) = (app.zValues(length(app.zValues)-1))/(t3-time(end)); % calculates the final speed (the time between the last frame and the time the ball hits the ground is not constant so t3 - the final t value is used to find the time. as the final value is 0, the difference in heights is simply the final frame's y value.
                        if collision % if the projectile is travelling in the opposite direction, the x value should be negative
                            app.xValues(length(app.xValues)+1) = app.xValues(end) - app.initialForward*(t3-time(end)); % sets x value to the negative distance
                        else % if no collision has occured
                            app.xValues(length(app.xValues)+1) = app.xValues(end) + app.initialForward*(t3-time(end)); % sets x value to final distance
                        end % ends if statement
                        app.yValues(end+1) = app.initialHorizontal*t3;
                end % ends if statement
                
                if max(app.speeds) ~= 0 % if the maximum speed is above 0 ms^-1 the following code will execute
                    app.Verticalspeedms1Gauge.Limits = [0 max(app.speeds)]; % sets the range of values on the vertical speed gauge to 0 to the max speed
                end % ends the if statement

                
                app.TimeSlider.MinorTicks = [0:0.05:t3 t3]; % sets the slider markers to 0.05 intervals up to t3
                app.TimeSlider.MajorTicks = 0:0.1:t3; % sets the slider large markers to 0.1 intervals up to t3
                
                
                hold(app.UIAxes, 'on'); % turns the graph hold function on so previous plots are not overwritten
                
    
                ball = animatedline('Parent', app.UIAxes, 'Marker', 'o');
                pathTravelled = animatedline('Parent', app.UIAxes, 'Color','m');
    
                %axes('equal') maybe??
                app.axisScaler();


                plot3(app.UIAxes, app.xValues, app.yValues, app.zValues, 'LineStyle',':'); % plots the trajectory path as a dotted line
    
                app.plotWall();
                time(end+1) = t3;
    
                for i = 1:length(app.xValues) 
                   clearpoints(ball) % clears previously drawn projectile
                    addpoints(ball, app.xValues(i), app.yValues(i), app.zValues(i));  % updates projectile position
                    addpoints(pathTravelled, app.xValues(i), app.yValues(i), app.zValues(i)); % updates the projectile path travelled
                    app.Verticalspeedms1Gauge.Value = app.speeds(i); % updates the speed guage speed
                    drawnow; % plots the updated positions
                    app.TimeSlider.Value = time(i);
                    app.Label_2.Text = num2str(time(i));
    
                    %%quiver(current x, current y, magnitude x, magnitude y, 'color', 'k') shows velocity vector
                end
                
                cla(app.UIAxes); % clears axes
                app.plotWall(); % replots wall
                
                app.TimeSlider.Enable = "on";
                app.TimeSlider.Value = t3; % sets timeSlider value to
                app.plotLine(t3);
    
                app.RealtimeLamp.Color = [1, 0, 0];
            else
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The vectors selected are launched from height 0 have no upwards component therefore there is no trajectory to plot.';
            end
        end

        % Value changed function: LaunchHeightmEditField
        function LaunchHeightmEditFieldValueChanged(app, event)
            value = app.LaunchHeightmEditField.Value;
            if value >= 0
                app.initialHeight = double(value);
            else
                app.LaunchHeightmEditField.Value = app.initialHeight;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'Launch height must be greater than or equal to 0.';
            end
            
        end

        % Value changed function: DownwardAccellerationEditField
        function DownwardAccellerationEditFieldValueChanged(app, event)
            value = app.DownwardAccellerationEditField.Value;
            if value > 0
                app.accelleration = double(value);
            else
                app.accelleration.Value = app.accelleration;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'Downward accelleration must be greater than 0.';
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.wallPresent = ~app.wallPresent;
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            value = app.DistancemEditField.Value;
            if value > 0
                app.wallDistance = double(value);
            else
                app.DistancemEditField.Value = app.wallDistance;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The wall distance must be greater than 0.';
            end
            
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;
            
            if value > 0
                app.wallHeight = double(value);
            else
                app.HeightmEditField.Value = app.wallHeight;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The wall height must be greater than 0.';
            end
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            app.holdPaths = ~app.holdPaths;
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = double(event.Value); % sets a variable to the value of the time slider
            app.RealtimeLamp.Color = [1, 1, 0];
            app.Label_2.Text = num2str(changingValue);
            app.plotLine(changingValue);
        end

        % Button pushed function: RemovelastvectorButton
        function RemovelastvectorButtonPushed(app, event)
            app.magnitudeArray(end) = []; % removes last vector velocity from the array 
            app.verticalAngleArray(end) = []; % removes last vector angle from the array 
            app.horizontalAngleArray(end) = [];
            app.UITable.Data(end-1:end) = []; % removes the stored data of the last vector from the table data array
            app.UITable.Data = [app.UITable.Data(1:end-3)]; % updates the table

            if isempty(app.magnitudeArray) % if all vectors have been removed the following code will execute
               app.RemovelastvectorButton.Enable = "off"; % disables the button as there are no more elements to remove
               app.PlotTrajectoryButton.Enable = "off";
               app.UITable_2.Data = []; % clears the table for the resultant vector
               app.UITable_3.Data = []; % clears the table of components
            else
                app.resolveVectors(); % resolves the vectors in the updated list and lists components in the components table
            end % ends the if statement
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.RealtimeLamp.Color = [1, 0, 0];
        end

        % Value changed function: 
        % ProjectilecoefficientofrestitutionEditField
        function ProjectilecoefficientofrestitutionEditFieldValueChanged(app, event)
            value = app.ProjectilecoefficientofrestitutionEditField.Value;
            if value > 0 && value <=1
                app.coefficientOfRestitution = double(value);
            else
                app.ProjectilecoefficientofrestitutionEditField.Value = app.coefficientOfRestitution;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The coefficient of restitution must be greater than 0 and less than or equal to 1.';
            end
        end

        % Button pushed function: VerticalSpeedHelp
        function VerticalSpeedHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Vertical Speed ]';
            app.DescriptionLabel.Text = 'Displays the vertical speed in meters per second at the current or selected time.';
        end

        % Button pushed function: RealTimeHelp
        function RealTimeHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Real Time ]';
            app.DescriptionLabel.Text = 'Displays the current state of the graph. Green = projectile is moving in real time, Yellow = User is determining the position of the projectile, Red = projectile position is frozen.';
        end

        % Button pushed function: TimeHelp
        function TimeHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Time Panel ]';
            app.DescriptionLabel.Text = 'Altering the time slider will display the projectile position according to the time selected by the user. Large ticks = 0.1s, small ticks = 0.05s. The "Current Time / s" field displays the time selected by the user.';
        end

        % Button pushed function: LaunchHeightHelp
        function LaunchHeightHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Launch Height ]';
            app.DescriptionLabel.Text = 'Sets the initial height of the projectile in meters. If no value is entered, this value defaults to 0m';
        end

        % Button pushed function: RestitutionHelp
        function RestitutionHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Coefficient Of Restitution ]';
            app.DescriptionLabel.Text = 'Sets the coefficient of restitution of the projectile. The value must be between 0 and 1. If no value is entered, the simulation will run under ideal circumstances with a coefficient of 1 by default.';
        end

        % Button pushed function: AccellerationHelp
        function AccellerationHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Downwards Accelleration ]';
            app.DescriptionLabel.Text = 'Sets the rate of change of velocity downwards. If no value is entered, the simulation will model accelleration due to gravity at sea level on Earth (9.81ms^-1).';
        end

        % Button pushed function: ZAxisHelp
        function ZAxisHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Z axis toggle ]';
            app.DescriptionLabel.Text = 'Allows the user to input a z axis angle to allow the simulation to run in three dimensions. By default this is disabled.';
        end

        % Button pushed function: HoldHelp
        function HoldHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Hold previous paths ]';
            app.DescriptionLabel.Text = 'If enabled, the previously plotted trajectories will not be cleared when another trajectory is plotted so that they may be compared to eachother. By default this parameter is enabled.';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.HorizontalAngledegreesEditField.Enable = value;
            app.View3Button.Enable = value;
            if value == "On"
                app.View3Button.Value = 1;
                app.View3ButtonValueChanged();
                app.View1Button.Value = 0;
            else
                app.View3Button.Value = 0;
                app.View1Button.Value = 1;
                app.View1ButtonValueChanged();
            end
            
        end

        % Value changed function: View1Button
        function View1ButtonValueChanged(app, event)
            value = app.View1Button.Value;
            if value
                view(app.UIAxes, 0, 0);
                app.View2Button.Value = 0;
                app.View3Button.Value = 0;
            elseif ~app.View2Button.Value && ~app.View3Button.Value
                app.View1Button.Value = 1;
            end
        end

        % Value changed function: View2Button
        function View2ButtonValueChanged(app, event)
            value = app.View2Button.Value;
            if value
                view(app.UIAxes, 0, 90); 
                app.View1Button.Value = 0;
                app.View3Button.Value = 0;
            else
                app.View1Button.Value = 1;
                app.View1ButtonValueChanged();
            end
        end

        % Value changed function: View3Button
        function View3ButtonValueChanged(app, event)
            value = app.View3Button.Value;
            if value
                view(app.UIAxes, 45, 45);
                app.View1Button.Value = 0;
                app.View2Button.Value = 0;
            else
                app.View1Button.Value = 1;
                app.View1ButtonValueChanged();
            end
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            value = app.ThemeDropDown.Value;
            switch value % this is done as a switch to allow themes to be added easily
                case "Light"
                    app.Slider.Enable = "off";
                    app.UIFigure.Color = [1, 0.95, 0.98];
                    app.ProjectileSimulatorLabel.FontColor = [0, 0, 0];
                    app.brightness = 0.98;
                    app.brightnessControl();
                    app.HorizontalAngledegreesLabel.FontColor = [0, 0, 0];
                    app.VerticalAngledegreesLabel.FontColor = [0, 0, 0];
                    app.Velocityms1Label.FontColor = [0, 0, 0];
                    app.VerticalAngledegreesLabel.FontColor = [0, 0, 0];
                    app.WalltoggleLabel.FontColor = [0, 0, 0];
                    app.DistancemLabel.FontColor = [0, 0, 0];
                    app.HeightmLabel.FontColor = [0, 0, 0];
                    app.Switch.FontColor = [0, 0, 0];
                    app.Switch_2.FontColor = [0, 0, 0];
                    app.Switch_3.FontColor = [0, 0, 0];
                    app.ResultantVectorLabel.FontColor = [0, 0, 0];
                    app.ComponentsLabel.FontColor = [0, 0, 0];
                    app.LaunchHeightmLabel.FontColor = [0, 0, 0];
                    app.CoefficientofrestitutionLabel.FontColor = [0, 0, 0];
                    app.DVectorToggleLabel.FontColor = [0, 0, 0];
                    app.HoldpreviouspathsLabel.FontColor = [0, 0, 0];
                    app.BrightnessLabel.FontColor = [0, 0, 0];
                    app.ParameterLabel.FontColor = [0, 0, 0];
                    app.DescriptionLabel.FontColor = [0, 0, 0];
                    app.DownwardAccellerationLabel.FontColor = [0, 0, 0];
                    app.CurrentTimesLabel.FontColor = [0, 0, 0];
                    app.UIAxes.Color = [1, 1, 1];
                    app.UIAxes.XColor = [0, 0, 0];
                    app.UIAxes.YColor = [0, 0, 0];
                    app.UIAxes.ZColor = [0, 0, 0];
                    app.UIAxes.Title.Color = [0, 0, 0];
                    app.UIAxes.GridColor = [0, 0, 0];
                case "Dark"
                    app.Slider.Enable = "on";
                    app.UIFigure.Color = [0, 0, 0];
                    app.ProjectileSimulatorLabel.FontColor = [1, 0.95, 1];
                    app.brightness = 0.075;
                    app.brightnessControl();
                    app.HorizontalAngledegreesLabel.FontColor = [1, 1, 1];
                    app.VerticalAngledegreesLabel.FontColor = [1, 1, 1];
                    app.Velocityms1Label.FontColor = [1, 1, 1];
                    app.VerticalAngledegreesLabel.FontColor = [1, 1, 1];
                    app.WalltoggleLabel.FontColor = [1, 1, 1];
                    app.DistancemLabel.FontColor = [1, 1, 1];
                    app.HeightmLabel.FontColor = [1, 1, 1];
                    app.Switch.FontColor = [1, 1, 1];
                    app.Switch_2.FontColor = [1, 1, 1];
                    app.Switch_3.FontColor = [1, 1, 1];
                    app.ResultantVectorLabel.FontColor = [1, 1, 1];
                    app.ComponentsLabel.FontColor = [1, 1, 1];
                    app.LaunchHeightmLabel.FontColor = [1, 1, 1];
                    app.CoefficientofrestitutionLabel.FontColor = [1, 1, 1];
                    app.DVectorToggleLabel.FontColor = [1, 1, 1];
                    app.HoldpreviouspathsLabel.FontColor = [1, 1, 1];
                    app.BrightnessLabel.FontColor = [1, 1, 1];
                    app.ParameterLabel.FontColor = [1, 1, 1];
                    app.DescriptionLabel.FontColor = [1, 1, 1];
                    app.DownwardAccellerationLabel.FontColor = [1, 1, 1];
                    app.CurrentTimesLabel.FontColor = [1, 1, 1];
                    app.UIAxes.Color = [0, 0, 0];
                    app.UIAxes.XColor = [1, 1, 1];
                    app.UIAxes.YColor = [1, 1, 1];
                    app.UIAxes.ZColor = [1, 1, 1];
                    app.UIAxes.Title.Color = [1, 1, 1];
                    app.UIAxes.GridColor = [1, 1, 1];
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.brightness = changingValue;
            app.brightnessControl();
        end

        % Callback function: not associated with a component
        function Velocityms1EditFieldValueChanged(app, event)

        end

        % Value changed function: UpwardsAngledegreesEditField
        function UpwardsAngledegreesEditFieldValueChanged(app, event)
            value = app.UpwardsAngledegreesEditField.Value;
            if value < 0 || value > 90
                app.UpwardsAngledegreesEditField.Value = 0;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The vertical angle should be between 0 and 90 degrees inclusive.';
            end
        end

        % Value changed function: HorizontalAngledegreesEditField
        function HorizontalAngledegreesEditFieldValueChanged(app, event)
            value = app.HorizontalAngledegreesEditField.Value;
            if value < -90 || value > 90
                app.HorizontalAngledegreesEditField.Value = 0;
                app.ParameterLabel.Text = 'Error!';
                app.DescriptionLabel.Text = 'The horizontal angle should be between -90 and 90 degrees inclusive.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitialVectorInputPanel = uipanel(app.UIFigure);
            app.InitialVectorInputPanel.Title = 'Initial Vector Input Panel';
            app.GridLayout = uigridlayout(app.InitialVectorInputPanel);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'no.'; 'Magnitude'; 'V. Angle'; 'H Angle'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.Velocityms1EditField = uieditfield(app.GridLayout, 'numeric');
            app.UpwardsAngledegreesEditField = uieditfield(app.GridLayout, 'numeric');
            app.UpwardsAngledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @UpwardsAngledegreesEditFieldValueChanged, true);
            app.HorizontalAngledegreesEditField = uieditfield(app.GridLayout, 'numeric');
            app.HorizontalAngledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalAngledegreesEditFieldValueChanged, true);
            app.HorizontalAngledegreesEditField.Enable = 'off';
            app.SubmitvectorButton = uibutton(app.GridLayout, 'push');
            app.SubmitvectorButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitvectorButtonPushed, true);
            app.SubmitvectorButton.Text = 'Submit vector';
            app.RemovelastvectorButton = uibutton(app.GridLayout, 'push');
            app.RemovelastvectorButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastvectorButtonPushed, true);
            app.RemovelastvectorButton.Enable = 'off';
            app.RemovelastvectorButton.Text = 'Remove last vector';
            app.Velocityms1Label = uilabel(app.GridLayout);
            app.Velocityms1Label.Text = 'Velocity / ms^-1';
            app.VerticalAngledegreesLabel = uilabel(app.GridLayout);
            app.VerticalAngledegreesLabel.Text = 'Vertical Angle / degrees';
            app.HorizontalAngledegreesLabel = uilabel(app.GridLayout);
            app.HorizontalAngledegreesLabel.Text = 'Horizontal Angle / degrees';
            app.ProjectileSimulatorLabel = uilabel(app.UIFigure);
            app.ProjectileSimulatorLabel.Text = 'Projectile Simulator';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.GridLayout6 = uigridlayout(app.GraphPanel);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'Forward Displacement / m')
            ylabel(app.UIAxes, 'Left/Right Displacement / m')
            zlabel(app.UIAxes, 'Vertical Displacement / m')
            app.PlotTrajectoryButton = uibutton(app.GridLayout6, 'push');
            app.PlotTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonPushed, true);
            app.PlotTrajectoryButton.Enable = 'off';
            app.PlotTrajectoryButton.Text = 'Plot Trajectory';
            app.RealTimePanel = uipanel(app.GridLayout6);
            app.RealTimePanel.Title = 'Real Time';
            app.GridLayout2 = uigridlayout(app.RealTimePanel);
            app.RealtimeLamp = uilamp(app.GridLayout2);
            app.RealTimeHelp = uibutton(app.GridLayout2, 'push');
            app.RealTimeHelp.ButtonPushedFcn = createCallbackFcn(app, @RealTimeHelpButtonPushed, true);
            app.RealTimeHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.RealTimeHelp.Text = '';
            app.VerticalSpeedPanel = uipanel(app.GridLayout6);
            app.VerticalSpeedPanel.Title = 'Vertical Speed';
            app.GridLayout3 = uigridlayout(app.VerticalSpeedPanel);
            app.Verticalspeedms1Gauge = uigauge(app.GridLayout3, 'linear');
            app.Verticalspeedms1Gauge.Limits = [0 30];
            app.VerticalSpeedHelp = uibutton(app.GridLayout3, 'push');
            app.VerticalSpeedHelp.ButtonPushedFcn = createCallbackFcn(app, @VerticalSpeedHelpButtonPushed, true);
            app.VerticalSpeedHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.VerticalSpeedHelp.Text = '';
            app.TimesPanel = uipanel(app.GridLayout6);
            app.TimesPanel.Title = 'Time / s';
            app.GridLayout4 = uigridlayout(app.TimesPanel);
            app.TimeSlider = uislider(app.GridLayout4);
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {''};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeSlider.Enable = 'off';
            app.CurrentTimesLabel = uilabel(app.GridLayout4);
            app.CurrentTimesLabel.Text = 'Current Time / s :';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '0';
            app.TimeHelp = uibutton(app.GridLayout4, 'push');
            app.TimeHelp.ButtonPushedFcn = createCallbackFcn(app, @TimeHelpButtonPushed, true);
            app.TimeHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.TimeHelp.Text = '';
            app.ViewPanel = uipanel(app.GridLayout6);
            app.ViewPanel.Title = 'View';
            app.GridLayout5 = uigridlayout(app.ViewPanel);
            app.View3Button = uibutton(app.GridLayout5, 'state');
            app.View3Button.ValueChangedFcn = createCallbackFcn(app, @View3ButtonValueChanged, true);
            app.View3Button.Enable = 'off';
            app.View3Button.Text = '3D';
            app.View2Button = uibutton(app.GridLayout5, 'state');
            app.View2Button.ValueChangedFcn = createCallbackFcn(app, @View2ButtonValueChanged, true);
            app.View2Button.Text = 'Top';
            app.View1Button = uibutton(app.GridLayout5, 'state');
            app.View1Button.ValueChangedFcn = createCallbackFcn(app, @View1ButtonValueChanged, true);
            app.View1Button.Text = 'Side';
            app.View1Button.Value = true;
            app.VectorResolutionPanel = uipanel(app.UIFigure);
            app.VectorResolutionPanel.Title = 'Vector Resolution Panel';
            app.GridLayout7 = uigridlayout(app.VectorResolutionPanel);
            app.UITable_2 = uitable(app.GridLayout7);
            app.UITable_2.ColumnName = {'Magnitude'; 'V. Angle'; 'H. Angle'};
            app.UITable_2.RowName = {};
            app.UITable_2.Multiselect = 'off';
            app.ResolvevectorButton = uibutton(app.GridLayout7, 'push');
            app.ResolvevectorButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvevectorButtonPushed, true);
            app.ResolvevectorButton.Text = 'Resolve vector';
            app.UITable_3 = uitable(app.GridLayout7);
            app.UITable_3.ColumnName = {'Direction'; 'Magnitude'};
            app.UITable_3.RowName = {};
            app.UITable_3.Multiselect = 'off';
            app.ResultantVectorLabel = uilabel(app.GridLayout7);
            app.ResultantVectorLabel.Text = 'Resultant Vector';
            app.ComponentsLabel = uilabel(app.GridLayout7);
            app.ComponentsLabel.Text = 'Components';
            app.AdvancedParameterControlPanel = uipanel(app.UIFigure);
            app.AdvancedParameterControlPanel.Title = 'Advanced Parameter Control Panel';
            app.GridLayout8 = uigridlayout(app.AdvancedParameterControlPanel);
            app.LaunchHeightmEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LaunchHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @LaunchHeightmEditFieldValueChanged, true);
            app.ProjectilecoefficientofrestitutionEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ProjectilecoefficientofrestitutionEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectilecoefficientofrestitutionEditFieldValueChanged, true);
            app.ProjectilecoefficientofrestitutionEditField.Value = 1;
            app.DVectorToggleLabel = uilabel(app.GridLayout8);
            app.DVectorToggleLabel.Text = '3D Vector Toggle';
            app.DownwardAccellerationEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DownwardAccellerationEditField.ValueChangedFcn = createCallbackFcn(app, @DownwardAccellerationEditFieldValueChanged, true);
            app.DownwardAccellerationEditField.Value = 9.81;
            app.Switch = uiswitch(app.GridLayout8, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.HoldpreviouspathsLabel = uilabel(app.GridLayout8);
            app.HoldpreviouspathsLabel.Text = 'Hold previous paths';
            app.Switch_3 = uiswitch(app.GridLayout8, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.CoefficientofrestitutionLabel = uilabel(app.GridLayout8);
            app.CoefficientofrestitutionLabel.Text = 'Coefficient of restitution';
            app.LaunchHeightHelp = uibutton(app.GridLayout8, 'push');
            app.LaunchHeightHelp.ButtonPushedFcn = createCallbackFcn(app, @LaunchHeightHelpButtonPushed, true);
            app.LaunchHeightHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.LaunchHeightHelp.Text = '';
            app.RestitutionHelp = uibutton(app.GridLayout8, 'push');
            app.RestitutionHelp.ButtonPushedFcn = createCallbackFcn(app, @RestitutionHelpButtonPushed, true);
            app.RestitutionHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.RestitutionHelp.Text = '';
            app.AccellerationHelp = uibutton(app.GridLayout8, 'push');
            app.AccellerationHelp.ButtonPushedFcn = createCallbackFcn(app, @AccellerationHelpButtonPushed, true);
            app.AccellerationHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.AccellerationHelp.Text = '';
            app.ZAxisHelp = uibutton(app.GridLayout8, 'push');
            app.ZAxisHelp.ButtonPushedFcn = createCallbackFcn(app, @ZAxisHelpButtonPushed, true);
            app.ZAxisHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.ZAxisHelp.Text = '';
            app.HoldHelp = uibutton(app.GridLayout8, 'push');
            app.HoldHelp.ButtonPushedFcn = createCallbackFcn(app, @HoldHelpButtonPushed, true);
            app.HoldHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.HoldHelp.Text = '';
            app.LaunchHeightmLabel = uilabel(app.GridLayout8);
            app.LaunchHeightmLabel.Text = 'Launch Height / m';
            app.DownwardAccellerationLabel = uilabel(app.GridLayout8);
            app.DownwardAccellerationLabel.Text = 'Downward Accelleration';
            app.WallConfigurationPanel = uipanel(app.UIFigure);
            app.WallConfigurationPanel.Title = 'Wall Configuration Panel';
            app.GridLayout10 = uigridlayout(app.WallConfigurationPanel);
            app.DistancemEditField = uieditfield(app.GridLayout10, 'numeric');
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.HeightmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 10;
            app.WalltoggleLabel = uilabel(app.GridLayout10);
            app.WalltoggleLabel.Text = 'Wall toggle';
            app.Switch_2 = uiswitch(app.GridLayout10, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.DistancemLabel = uilabel(app.GridLayout10);
            app.DistancemLabel.Text = 'Distance / m';
            app.HeightmLabel = uilabel(app.GridLayout10);
            app.HeightmLabel.Text = 'Height / m';
            app.HelpPanel = uipanel(app.UIFigure);
            app.HelpPanel.Title = 'Help Panel';
            app.GridLayout9 = uigridlayout(app.HelpPanel);
            app.ParameterLabel = uilabel(app.GridLayout9);
            app.ParameterLabel.Text = '[Parameter]';
            app.DescriptionLabel = uilabel(app.GridLayout9);
            app.DescriptionLabel.Text = 'Click on a [?] button to display information about the corrosponding component here.';
            app.ThemePanel = uipanel(app.UIFigure);
            app.ThemePanel.Title = 'Theme';
            app.GridLayout11 = uigridlayout(app.ThemePanel);
            app.ThemeDropDown = uidropdown(app.GridLayout11);
            app.ThemeDropDown.Items = {'Light', 'Dark'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light';
            app.Slider = uislider(app.GridLayout11);
            app.Slider.Limits = [0 0.2];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 0.075;
            app.BrightnessLabel = uilabel(app.GridLayout11);
            app.BrightnessLabel.Text = 'Brightness';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MajorProject1V9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end