classdef ProjectileSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThemePanel                      matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        BrightnessLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        ThemeDropDown                   matlab.ui.control.DropDown
        HelpPanel                       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        DescriptionLabel                matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        WallConfigurationPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        HeightmLabel                    matlab.ui.control.Label
        DistancemLabel                  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        WalltoggleLabel                 matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        DistancemEditField              matlab.ui.control.NumericEditField
        AdvancedParameterControlPanel   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        DownwardAccellerationLabel      matlab.ui.control.Label
        LaunchHeightmLabel              matlab.ui.control.Label
        HoldHelp                        matlab.ui.control.Button
        ZAxisHelp                       matlab.ui.control.Button
        AccellerationHelp               matlab.ui.control.Button
        RestitutionHelp                 matlab.ui.control.Button
        LaunchHeightHelp                matlab.ui.control.Button
        CoefficientofrestitutionLabel   matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        HoldpreviouspathsLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        DownwardAccellerationEditField  matlab.ui.control.NumericEditField
        DVectorToggleLabel              matlab.ui.control.Label
        ProjectilecoefficientofrestitutionEditField  matlab.ui.control.NumericEditField
        LaunchHeightmEditField          matlab.ui.control.NumericEditField
        VectorResolutionPanel           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ComponentsLabel                 matlab.ui.control.Label
        ResultantVectorLabel            matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        ResolvevectorButton             matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        GraphPanel                      matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ProjectileInformationPanel      matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        zCoordinate                     matlab.ui.control.Label
        yCoordinate                     matlab.ui.control.Label
        xCoordinate                     matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ZComponentLabel                 matlab.ui.control.Label
        YComponentLabel                 matlab.ui.control.Label
        XComponentLabel                 matlab.ui.control.Label
        CoordinatesLabel                matlab.ui.control.Label
        ViewPanel                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        View1Button                     matlab.ui.control.StateButton
        View2Button                     matlab.ui.control.StateButton
        View3Button                     matlab.ui.control.StateButton
        TimesPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TimeHelp                        matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        CurrentTimesLabel               matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        VerticalSpeedPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        VerticalSpeedHelp               matlab.ui.control.Button
        Verticalspeedms1Gauge           matlab.ui.control.LinearGauge
        RealTimePanel                   matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        RealTimeHelp                    matlab.ui.control.Button
        RealtimeLamp                    matlab.ui.control.Lamp
        PlotTrajectoryButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ProjectileSimulatorLabel        matlab.ui.control.Label
        InitialVectorInputPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        HorizontalAngledegreesLabel     matlab.ui.control.Label
        VerticalAngledegreesLabel       matlab.ui.control.Label
        Velocityms1Label                matlab.ui.control.Label
        RemovelastvectorButton          matlab.ui.control.Button
        SubmitvectorButton              matlab.ui.control.Button
        HorizontalAngledegreesEditField  matlab.ui.control.NumericEditField
        UpwardsAngledegreesEditField    matlab.ui.control.NumericEditField
        Velocityms1EditField            matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        magnitudeArray = []; % initialises array to store initial magnitudes entered by user
        verticalAngleArray = []; % initialises array to store vertical angles corrosponding to the velocities entered by user
        horizontalAngleArray = []; % initialises array to store the horizontal angles entered by user

        speeds = []; % initialises the array for vertical speeds

        xValues = []; % forward displacement array
        yValues = []; % side displacement array
        zValues = []; % height array

        initialVertical = 0; % initialises vertical component variable
        initialForward = 0; % initialises forward component variable
        initialHorizontal = 0; % initialises horizontal component variable

        initialHorizontalAngle = 0; % initialises the initial horizontal angle variable

        initialHeight = 0; % sets default value for initial height to 0
        accelleration = 9.81; % sets default value for downwards accelleration to the value of accelleration due to gravity on Earth at sea level
        coefficientOfRestitution = 1; % sets the default value of coefficient of restitution to an ideal model

        wallPresent = 0; % sets the default value for presence of walls to false
        wallDistance = 10; % sets the default distance of the wall from the launch site to 0
        wallHeight = 10; % sets the default height of the wall to 0

        holdPaths = true; % sets the default preference for previous plotted trajectory holding to true

        brightness = 0.075; % sets the default brightness for the dark theme

        maxT = 0; % initialises variable for time of max height
        timeOfCollision = 0; % initialises variable for time of collision

    end
    
    methods (Access = private)
        function resolveVectors(app) % function to resolve the vectors entered by the user

            verticalComponent = 0; % initialises the vertical component variable
            intermediate = 0; % initialises the intermediate component variable
            horizontalComponent = 0; % initialises the horizontal component variable

            for i = 1:length(app.magnitudeArray) % for the number of vectors input by the user the following code will execute
                verticalComponent = verticalComponent + app.magnitudeArray(i)*sind(app.verticalAngleArray(i)); % calculates the vertical component of the vector no. i in the list and adds it to the previously calculated vertical components
                if app.verticalAngleArray(i) ~= 90 && app.verticalAngleArray(i) ~= 0 % if the vertical angle is not 90 or 0 the following code will execute
                    intermediate = intermediate + (app.magnitudeArray(i)*sind(app.verticalAngleArray(i)))/tand(app.verticalAngleArray(i)); % calculates the intermediate vector used to calculate the horizontal and forward displacement later and adds it to previously calculated components
                elseif app.verticalAngleArray(i) == 0 % if the vertical angle is 0 the following code will execute
                    intermediate = intermediate + app.magnitudeArray(i); % adds the magnitude of the vector no. i to the intermediate vector (resultant of horizontal and forward components)
                end % ends the if statement
            end % ends the for loop

            for i = 1:length(app.horizontalAngleArray) % for the number of horizontal angles entered the following code will execute
                if app.horizontalAngleArray(i) ~= 0 % if the angle is not 0, the following code will execute
                    horizontalComponent = horizontalComponent + intermediate*sind(app.horizontalAngleArray(i)); % calculates the horizontal component of vector no. i and adds it to the previously calculated horizontal components
                end % ends the if statement
            end % ends the for loop
            
            if horizontalComponent ~= 0 % if the horizontal component is not 0 the following code will execute
                forwardComponent = (intermediate^2-horizontalComponent^2)^0.5; % the resultant forward component is calculated
                horizontalAngle = asind(horizontalComponent/intermediate); % the resultant horizontal angle is calculated
            else % otherwise the following code will execute
                forwardComponent = intermediate; % sets the forward component to the intermediate vector magnitude
                horizontalAngle = 0; % sets the resultant horizontal angle to 0 as there is no horizontal displacement
            end % ends the if statement
            
            verticalAngle = atand(verticalComponent/intermediate); % calculates the resultant vertical angle
            if isinf(verticalAngle) % if the calculated resultant vertical angle is infinite the following code will execute
                verticalAngle = 90; % the resultant calculated angle is set to 90
            elseif isnan(verticalAngle) % if the calculated resultant vertical angle is not a number the following code will execute
                verticalAngle = 0; % the resultant calculated angle is set to 0
            elseif verticalAngle ~= 0 && verticalAngle ~= 90 % if the calcultated resultant vertical angle is not 0 or 90 the following code will execute
                verticalAngle = 90 - verticalAngle; % the resultant vertical angle is set to 90 - the calculated vertical angle (this is due to the angle 0 degrees not being measured from the horizontal)
            end % ends the if statement

            if verticalAngle == 0 % if the vertical angle is 0 the following code will execute
                if horizontalComponent ~= 0 && forwardComponent == 0 % if the horizontal component is not 0 but the forward component is 0 the following code will execute
                    magnitude = horizontalComponent; % the resultant magnitude is set to the horizontal component
                elseif horizontalComponent == 0 && forwardComponent ~= 0 % if the horizontal component is 0 but the forward component is not 0 the following code will execute
                    magnitude = forwardComponent; % the resultant magnitude is set to the forward component
                elseif horizontalComponent ~= 0 && forwardComponent ~= 0 % if the horizontal and forward components are not 0 the following code will execute
                    magnitude = (horizontalComponent^2+forwardComponent^2)^0.5; % calculates the resultant magnitude
                elseif horizontalComponent == 0 && forwardComponent == 0 % if both the horizontal and forward components are 0 the following code will execute
                    magnitude = 0; % the resultant magnitude is set to 0
                end % ends the nested if statment
            elseif verticalAngle == 90 % if the vertical angle is 90 the following code will execute
                magnitude = verticalComponent; % the resultant magnitude is set to the vertical component
            else % otherwise the following code will execute
                magnitude = verticalComponent/sind(verticalAngle); % calculates the resultant magnitude
            end % ends the if statement
            
            app.initialHorizontalAngle = horizontalAngle; % makes the horizontal accessible to other functions

            app.UITable_2.Data = {magnitude, verticalAngle, horizontalAngle}; % adds the resultant angle and velocity to the resultant table
            app.UITable_3.Data = {'Forward' forwardComponent ; 'Vertical' verticalComponent ; 'Horizontal' horizontalComponent}; % adds the horizontal and vertical components to the component table

            app.initialVertical = verticalComponent; % assigns vertical component to the global variable so that it can be accessed outside the function
            app.initialForward = forwardComponent; % assigns forward component to the global variable so that it can be accessed outside the function
            app.initialHorizontal = horizontalComponent; % assigns horizontal component to the global variable so that it can be accessed outside the function 
        end % ends the function

        function plotLine(app, changingValue) % function to plot the trajectory at a given time
            cla(app.UIAxes); % clears the previous lines
            app.plotWall(); % calls function to plot the wall on the axes
            ball = animatedline('Parent', app.UIAxes, 'Marker', 'o'); % assigns parameters to the projectile location marker
            pathTravelled = animatedline('Parent', app.UIAxes, 'Color','m'); % assigns parameters to the trajectory line
            clearpoints(ball); % clears previously drawn points for the trajectory
            clearpoints(pathTravelled); % clears previously drawn points for the path travelled
            addpoints(pathTravelled, app.xValues(1:1:ceil(changingValue/0.05)+1), app.yValues(1:1:ceil(changingValue/0.05)+1), app.zValues(1:1:ceil(changingValue/0.05)+1));  % updates projectile position
            addpoints(ball, app.xValues(ceil(changingValue/0.05)+1), app.yValues(ceil(changingValue/0.05)+1), app.zValues(ceil(changingValue/0.05)+1)); % updates the projectile trajectory
            app.Verticalspeedms1Gauge.Value = app.speeds(ceil(changingValue/0.05)+1); % updates the speed guage with the speed at the time selected
            drawnow nocallbacks limitrate; % plots the updated positions
            app.projectileInformation(changingValue)
        end % ends the function

        function plotWall(app) % function to plot the wall corrosponding to parameters set by user
            if app.wallPresent == true % if a wall is present the following code will execute
                if ~isempty(app.yValues) && min(app.yValues) ~= max(app.yValues) % if there is horizontal movement the wall must be plotted in 3d space and therefore the following code will execute
                    x = app.wallDistance; % sets variable x to the wall distance
                    patch(app.UIAxes, [x x x x x], [min(app.yValues) max(app.yValues) max(app.yValues) min(app.yValues) min(app.yValues)], [0 0 app.wallHeight app.wallHeight 0], 'k'); % draws a rectangle to represent the wall
                else % otherwise the following code will execute
                    plot3(app.UIAxes, linspace(app.wallDistance,app.wallDistance), linspace(0,0), linspace(0,app.wallHeight), 'color', 'k', 'LineWidth', 5); % plots the wall on the graph in 2d
                end % ends the nested if statement
            end % ends the if statement
        end % ends the function

        function axisScaler(app) % fucntion to linearly scale the axes for vertical and forward displacement for an accurate trajectory representation
            if max(app.zValues) < app.wallHeight && app.wallHeight >= max(app.xValues) - min(app.xValues) % if the wall height is greater than the maximum height of the trajectory, the Y axis should scale to fit the wall
                app.UIAxes.ZLim = [0 app.wallHeight]; % sets the z axis limit to 0 to the height of the wall
                app.UIAxes.XLim = [min(app.xValues) app.wallHeight]; % sets the x axis limits from the smallest x value to the wall height
            elseif max(app.zValues) >= max(app.xValues) - min(app.xValues) % if max height is greater than the x displacement, the following code will execute
                app.UIAxes.ZLim = [0 max(app.zValues)]; % sets the z axis limit to 0 to the maximum y value of the trajectory
                app.UIAxes.XLim = [min(app.xValues) max(app.zValues)]; % sets the x axis limits from the smallest x value to the greatest z value
            else % otherwise
                app.UIAxes.ZLim = [0 max(app.xValues)-min(app.xValues)]; % the z axis limits are set from 0 to the range of x values
                app.UIAxes.XLim = [min(app.xValues) max(app.xValues)]; % the x axis limits are set to the range of x values
            end % ends the if statement
        end % ends the function

        function brightnessControl(app) % function to control the brightness of multiple components simultaneously
            app.GridLayout.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout2.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout3.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout4.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout5.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout6.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout7.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout8.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout9.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout10.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout11.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
            app.GridLayout12.BackgroundColor = [app.brightness, app.brightness, app.brightness]; % sets component background colour to a grey of brightness set by the user
        end % ends the function

        function projectileInformation(app, time) % function to update the projectile information panel with the current components and coordinates of the projectile at a given time
            if time > app.timeOfCollision % if a collision has occurred the following code will execute
                app.Label_4.Text = num2str(-app.initialForward*app.coefficientOfRestitution); % calculates the forward component and displays it
            else % otherwise the following code will execute
                app.Label_4.Text = num2str(app.initialForward); % displays the forward component
            end % ends the if statement
            
            if app.initialHorizontalAngle < 0 % if the initial horizontal angle is less than 0 the following code will execute
                app.Label_5.Text = num2str(-app.initialHorizontal); % displays the horizontal component
            elseif app.initialHorizontalAngle > 0 % if thee initial horizontal angle is more than 0 the following code will execute
                app.Label_5.Text = num2str(app.initialHorizontal); % displays the horizontal component
            else % otherwise the following code will execute
                app.Label_5.Text = '0'; % if the horizontal angle is 0 there is no horizontal component
            end % ends the if statement

            if time > app.maxT % if the projectile is descending the following code will execute
                app.Label_6.Text = num2str(-app.speeds(ceil(time/0.05)+1)); % displays the vertical component
            else % otherwise the following code will execute
                app.Label_6.Text = num2str(app.speeds(ceil(time/0.05)+1)); % displays the vertical component
            end % ends the if statement

            app.xCoordinate.Text = num2str(app.xValues(ceil(time/0.05)+1)); % updates x coordinate with current value
            app.yCoordinate.Text = num2str(app.yValues(ceil(time/0.05)+1)); % updates y coordinate with current value
            app.zCoordinate.Text = num2str(app.zValues(ceil(time/0.05)+1)); % updates z coordinate with current value
        end % ends the function

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitvectorButton
        function SubmitvectorButtonPushed(app, event)
            if app.Velocityms1EditField.Value > 0 && app.Velocityms1EditField.Value < 1000 % if the velocity set by the user is greater than 0 the following code will execute
                updatedData = [(length(app.magnitudeArray)+1) app.Velocityms1EditField.Value app.UpwardsAngledegreesEditField.Value app.HorizontalAngledegreesEditField.Value]; % sets user input values as a variable to be appended as a new row in the table
                app.UITable.Data = [app.UITable.Data;updatedData]; % adds the data to the next row of the table
    
                app.magnitudeArray(length(app.magnitudeArray)+1) = app.Velocityms1EditField.Value; % adds the user input to the magnitude array to be resolved later
                app.verticalAngleArray(length(app.verticalAngleArray)+1) = app.UpwardsAngledegreesEditField.Value; % adds the vertical angle to the array to be resolved later
                app.horizontalAngleArray(length(app.verticalAngleArray)+1) = app.HorizontalAngledegreesEditField.Value; % adds the horizontal angle to the array to be resolved later
    
                app.RemovelastvectorButton.Enable = "on"; % enables the remove vector button
                app.PlotTrajectoryButton.Enable = "on"; % enables the plot trajectory button
    
                app.Velocityms1EditField.Value = 0; % resets the velocity input field to 0
                app.UpwardsAngledegreesEditField.Value = 0; % resets the angle input field to 0
                app.HorizontalAngledegreesEditField.Value = 0; % resets the angle input field to 0
            else % otherwise
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The velocity should be greater than 0 and less than 1000.'; % adds information to description
            end % ends the if statement
        end

        % Button pushed function: ResolvevectorButton
        function ResolvevectorButtonPushed(app, event)
            resolveVectors(app); % calls function to resolve vectors. This is a separate function as it must also be called from other callbacks
        end

        % Button pushed function: PlotTrajectoryButton
        function PlotTrajectoryButtonPushed(app, event)
            
            resolveVectors(app); % resolves all vectors in the table
            if app.initialVertical ~= 0 || app.initialHeight ~= 0 % if the initial vertical component is not 0 or the initial height is not 0 the following code will execute
                app.RealtimeLamp.Color = [0, 1, 0]; % sets the real time indication lamp colour to green
                if app.holdPaths == false % if the hold paths button is in the off position
                    cla(app.UIAxes) % clears previous paths
                end % ends if statement
                
                
                collision = false; % sets the collision checker to false
                xPostCollision = 0; % initialises the x value at collision
                app.speeds = []; % clears the y speeds array
                app.xValues = []; % clears the xValues array
                app.yValues = []; % clears the yValues array
                app.zValues = []; % clears the zValues array
    
                zCollisionNotAdded = true; % sets the value of a variable which ensures that an if statement within a for loop only executes once
    

                % finding initial upwards vertical displacement (s)
                u = app.initialVertical; % sets initial velocity as the vertical component of the resultant vector
                v = 0; % the final velocity should be 0 as this is when the maximum height has been reached
                a = -app.accelleration; % gravity is decellerating the projectile as it travels upwards and therefore it should be a negative accelleration
                s = (-u^2)/(2*a); % calculates the displacement from the start to the maximum height
                
                % finding time for vertical ascent and descent (t1 and t2)
                t1 = (2*s)/(v+u); % calculates the time taken to reach maximum height
                t2 = 2*t1; % calculates the time taken to reach the initial height again
    
                % finding end velocity at return (v)
                a = app.accelleration; % sets accelleration due to gravity to a positive accelleration
                returnVelocity = (2*a*s)^0.5; % finds the final velocity when the projectile returns to its initial height. This will be the initial velocity of the calculation for the rest of the fall if the projectile was launched from a height
                
                % finding total time of projectile (t3)
                s = app.initialHeight; % sets the displacement to the initial height of the projectile
                u = returnVelocity; % sets the initial velocity to the final velocity of the previous fall
    
                if app.initialVertical == 0 % if the vertical component is 0 the following code will execute
                    u = 0; % sets initial speed to 0
                    t1 = 0; % sets time of maximum height to 0
                    t2 = 0; % sets time to reach initial height to 0
                end % ends the if statment

                app.maxT = t1; % makes the maximum height accessible for the functions used later

                v = (u^2 + 2*a*s)^0.5; % finds the final velocity when the projectile hits the ground

                t3 = t2 + s/((v+u)/2);  % calculates the total time taken for the projectile to reach the ground

                if app.wallPresent % if the user has specified that a wall is present the following code will execute
                    tCollision = app.wallDistance/app.initialForward; % calculates the exact time of the collision of the projectile with the wall
                    if tCollision <= t1 % if the time of the collision is on the ascent of the projectile the following code will execute
                        zCollision = app.initialVertical*tCollision + 0.5*-app.accelleration*tCollision^2; % calculates the z value at the point of collision
                    else % if the projectile is descending the following code will execute
                        zCollision = (app.initialVertical*t1+0.5*(-app.accelleration)*t1^2)-(0.5*app.accelleration*(tCollision-t1)^2); % calculates the z value at the point of collision
                    end % ends the nested if statement
                end % emds the if statement
    
                if t3 > 0 % t3 must be greater than 0 or an error will occur
                    app.TimeSlider.Limits = [0 t3]; % sets the upper limit of the time slider to the total time of the projectile flight
                end %ends if statement

                % preparing x, y and z values from data
                time = 0:0.05:t3; % as the animated line function is limited to 20fps, to obtain real time animation, a point should be made at every 20th of a second. To do this the division is set to 1/20 (0.05) and thus each frame has an x, y and z value.
    
                for i = 1:length(time) % for the number of frames in the given time, the following code will execute
                    if time(i) < t1 % if the time is less than or equal to the time of ascent, the following code will execute 
                        a = -app.accelleration; % accelleration is set to negative
                        u = app.initialVertical; % initial velocity is set to the resultant vertical component
                    else % if the time is more than or equal the total ascent time
                        a = app.accelleration; % accelleration is set to positive
                        u = 0; % initial velocity is set to 0
                    end % ends the if statement
                    
                    if time(i) <= t1 % if the projectile has not yet returned to the initial height the following code will execute
                        t = time(i); % time is set to the current frame time
                        app.zValues(i) = app.initialHeight + u*t+(a*t^2)/2; % the z value for this frame is calculated and stored
                    elseif time(i) <= t2 % if the projectile has begun its descent but has not passed its initial launch height the following code will execute
                        t = time(i) - t1; % sets the time elapsed since the projectile began its descent
                        app.zValues(i) = max(app.zValues) - (a*t^2)/2; % the z value for this frame is calculated and stored
                    elseif time(i) > t2 % if time is more than the total ascent and descent time, the projectile is below the initial height and the following code will execute
                        t = time(i) - t2; % sets the time elapsed since the projectile height superceeded the initial height
                        u = returnVelocity; % initial velocity is set to the final velocity when the projectile reaches initial height
                        app.zValues(i) = app.initialHeight - (u*t+(a*t^2)/2); % the z value for this frame is calculated and stored
                    end % ends the if statement
                    
                    if ~collision % if no collision has occured the following code will execute
                        x = app.initialForward*time(i); % calculates the distance travelled by the projectile at time t
                    else % otherwise the following code will execute
                        xPostCollision = -app.initialForward*app.coefficientOfRestitution*(time(i)-tCollision); % calculates the x displacement of the projectile at time t
                    end % ends the if statement
                    
                    if app.wallPresent && x >= app.wallDistance && app.xValues(i-1) <= app.wallDistance && app.zValues(i) <= app.wallHeight || collision % if the projectile is currently colliding or has collided with the wall
                        collision = true; % sets the collision variable to true so that when the loop returns to the if statement it will continue calculating x values in to correct direction
                        app.xValues(i) = app.wallDistance + xPostCollision; % the x displacement is added to the xValues array
                    else % if no collision has occurred, the following code will execute
                        app.xValues(i) = x; % the x value is stored
                    end % ends the if statement
                    
                    if collision && zCollisionNotAdded % if a collision has occured and this code has not yet been executed, the following code will execute
                        app.zValues(i) = zCollision + app.initialHeight; % sets the current iteration of the z value to the z value when the projectile collides with the wall
                        app.xValues(i) = app.wallDistance; % sets the current iteration of the x value to the distance of the wall from the projectile
                        time(i) = tCollision; % sets the current time to the time of the collision
                        zCollisionNotAdded = false; % sets a variable to ensure the code in this if statement only executes once
                    end % ends the if statement
                    
                    if i >=2 && time(i) <= t1 % if 2 or more iterations have occured and time is less than the time of the maximum height of the trajectory, the following code will execute
                        app.speeds(i) = (app.zValues(i) - app.zValues(i-1))/(time(i)-time(i-1)); % calculates the vertical speed at the current time value
                    elseif i >=2 && time(i) > t1 % if 2 or more iterations have occured and time is more than the time of the maximum height of the trajectory, the following code will execute
                        app.speeds(i) = (app.zValues(i-1) - app.zValues(i))/(time(i)-time(i-1)); % calculates the vertical speed at the current time value
                    else % if less than 2 iterations have occurred, the following code will execute
                        app.speeds(i) = app.initialVertical; % sets the speed at the first iteration to the initial vertical component of velocity
                    end % ends the if statement

                    app.yValues(i) = app.initialHorizontal*time(i); % calculates the horizontal displacement and stores it
                end % ends the for loop
    
                if app.zValues(end) ~= 0 % calculates the final x value when the projectile hits the ground so that it is plotted (as this may not always be on a 1/20th of a second time value)
                        app.zValues(length(app.zValues)+1) = 0; % final z value is set to 0
                        app.speeds(length(app.speeds)+1) = (app.zValues(length(app.zValues)-1))/(t3-time(end)); % calculates the final speed (the time between the last frame and the time the ball hits the ground is not constant so t3 - the final t value is used to find the time. as the final value is 0, the difference in heights is simply the previous frame's z value.
                        if collision % if the projectile is travelling in the opposite direction, the x value should be negative
                            app.xValues(length(app.xValues)+1) = app.xValues(end) - app.initialForward*(t3-time(end)); % sets x value to the negative distance
                        else % if no collision has occured
                            app.xValues(length(app.xValues)+1) = app.xValues(end) + app.initialForward*(t3-time(end)); % sets x value to final distance
                        end % ends if statement
                        app.yValues(end+1) = app.initialHorizontal*t3; % calculates the horizontal displacement
                end % ends if statement
                
                if max(app.speeds) ~= 0 % if the maximum speed is above 0 ms^-1 the following code will execute
                    app.Verticalspeedms1Gauge.Limits = [0 max(app.speeds)]; % sets the range of values on the vertical speed gauge to 0 to the max speed
                end % ends the if statement

                % preparing to plot
                app.TimeSlider.MinorTicks = [0:0.05:t3 t3]; % sets the slider markers to 0.05 intervals up to t3
                app.TimeSlider.MajorTicks = 0:0.1:t3; % sets the slider large markers to 0.1 intervals up to t3
                
                hold(app.UIAxes, 'on'); % turns the graph hold function on so previous plots are not overwritten
    
                ball = animatedline('Parent', app.UIAxes, 'Marker', 'o'); % assigns parameters to projectile position marker
                pathTravelled = animatedline('Parent', app.UIAxes, 'Color','m'); % assigns parameters to the trajectory line
    
                app.axisScaler(); % function to scale axis appropriately is called
    
                plot3(app.UIAxes, app.xValues, app.yValues, app.zValues, 'LineStyle',':'); % plots the full trajectory path as a dotted line
    
                app.plotWall(); % function to plot the wall is called
                time(end+1) = t3; % final time value is added to time array
                
                if ~collision % if no collision has occurred the following code will execute
                    tCollision = t3+1; % sets the time of collision to an unreachable value
                end % ends the if statement

                app.timeOfCollision = tCollision; % makes tCollision accessible by functions

                % plotting
                for i = 1:length(app.xValues) % for the number of x values in the array the following code will execute
                   clearpoints(ball) % clears previously drawn projectile
                    addpoints(ball, app.xValues(i), app.yValues(i), app.zValues(i));  % updates projectile position
                    addpoints(pathTravelled, app.xValues(i), app.yValues(i), app.zValues(i)); % updates the projectile path travelled
                    app.Verticalspeedms1Gauge.Value = app.speeds(i); % updates the speed guage speed
                    drawnow; % plots the updated positions
                    app.TimeSlider.Value = time(i); % sets the time slider value to the current time value
                    app.Label_2.Text = num2str(time(i)); % sets the current time label to the current time
                    app.projectileInformation(time(i)); % calls function to update projectile information panel
                end % ends the for loop
                
                cla(app.UIAxes); % clears axes
                app.plotWall(); % replots wall
                
                app.TimeSlider.Enable = "on"; % enables the time slider
                app.TimeSlider.Value = t3; % sets timeSlider value to maximum time value
                app.plotLine(t3); % calls function to plot the trajectory at the maximum time value
    
                app.RealtimeLamp.Color = [1, 0, 0]; % sets the real time lamp colour to red
            else % otherwise the following code will execute
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The vectors selected are launched from height 0 have no upwards component therefore there is no trajectory to plot.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: LaunchHeightmEditField
        function LaunchHeightmEditFieldValueChanged(app, event)
            value = app.LaunchHeightmEditField.Value; % assigns current field value to a variable
            if value >= 0 % if the value is greater than or equal to 0 the following code will execute
                app.initialHeight = double(value); % sets the initial height to the value entered by the user
            else % otherwise the following code will execute
                app.LaunchHeightmEditField.Value = app.initialHeight; % sets the field to the previous valid input
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'Launch height must be greater than or equal to 0.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: DownwardAccellerationEditField
        function DownwardAccellerationEditFieldValueChanged(app, event)
            value = app.DownwardAccellerationEditField.Value; % assigns current field value to a variable
            if value > 0 % if the value is greater than 0 the following code will execute
                app.accelleration = double(value); % sets the accelleration to the value entered by the user
            else % otherwise the following code will execute
                app.accelleration.Value = app.accelleration; % sets the field to the previous valid input
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'Downward accelleration must be greater than 0.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.wallPresent = ~app.wallPresent; % toggles the wall present variable
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            value = app.DistancemEditField.Value; % assigns current field value to a variable
            if value > 0 % if the value is greater than 0 the following code will execute
                app.wallDistance = double(value); % sets the wall distance to the value entered by the user
            else % otherwise the following code will execute
                app.DistancemEditField.Value = app.wallDistance; % sets the field to the previous valid input
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The wall distance must be greater than 0.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value; % assigns current field value to a variable
            if value > 0 % if the value is greater than 0 the following code will execute
                app.wallHeight = double(value); % sets the wall height to the value entered by the user
            else % otherwise the following code will execute
                app.HeightmEditField.Value = app.wallHeight; % sets the field to the previous valid input
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The wall height must be greater than 0.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            app.holdPaths = ~app.holdPaths; % toggles the hold paths variable
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = double(event.Value); % assigns the value of the time slider to a variable
            app.RealtimeLamp.Color = [1, 1, 0]; % sets the real time lamp colour to yellow
            app.Label_2.Text = num2str(changingValue); % sets the current time label to the time at the value selected
            app.plotLine(changingValue); % plots the projectile position and trajectory at the time selected
        end

        % Button pushed function: RemovelastvectorButton
        function RemovelastvectorButtonPushed(app, event)
            app.magnitudeArray(end) = []; % removes last vector magnitude from the array 
            app.verticalAngleArray(end) = []; % removes last vertical angle from the array 
            app.horizontalAngleArray(end) = []; % removes last horizontal angle from the array 
            app.UITable.Data(end-1:end) = []; % removes the stored data of the last vector from the table data array
            app.UITable.Data = [app.UITable.Data(1:end-3)]; % updates the table

            if isempty(app.magnitudeArray) % if all vectors have been removed the following code will execute
               app.RemovelastvectorButton.Enable = "off"; % disables this button as there are no more elements to remove
               app.PlotTrajectoryButton.Enable = "off"; % disables the plot trajectory button
               app.UITable_2.Data = []; % clears the table for the resultant vector
               app.UITable_3.Data = []; % clears the table of components
            else % otherwise the following code will execute
                app.resolveVectors(); % resolves the vectors in the updated list and lists components in the components table
            end % ends the if statement
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.RealtimeLamp.Color = [1, 0, 0]; % sets the real time lamp colour to red
        end

        % Value changed function: 
        % ProjectilecoefficientofrestitutionEditField
        function ProjectilecoefficientofrestitutionEditFieldValueChanged(app, event)
            value = app.ProjectilecoefficientofrestitutionEditField.Value; % assigns current field value to a variable
            if value > 0 && value <=1 % if the value is greater than 0 and less than or equal to 1 the following code will execute
                app.coefficientOfRestitution = double(value); % sets the coefficient of restitution to the value entered by the user
            else % otherwise the following code will execute
                app.ProjectilecoefficientofrestitutionEditField.Value = app.coefficientOfRestitution; % sets the field to the previous valid input
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The coefficient of restitution must be greater than 0 and less than or equal to 1.'; % adds information to description
            end % ends the if statement
        end

        % Button pushed function: VerticalSpeedHelp
        function VerticalSpeedHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Vertical Speed ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Displays the vertical speed in meters per second at the current or selected time.'; % adds information to description
        end

        % Button pushed function: RealTimeHelp
        function RealTimeHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Real Time ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Displays the current state of the graph. Green = projectile is moving in real time, Yellow = User is determining the position of the projectile, Red = projectile position is frozen.'; % adds information to description
        end

        % Button pushed function: TimeHelp
        function TimeHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Time Panel ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Altering the time slider will display the projectile position according to the time selected by the user. Large ticks = 0.1s, small ticks = 0.05s. The "Current Time / s" field displays the time selected by the user.'; % adds information to description
        end

        % Button pushed function: LaunchHeightHelp
        function LaunchHeightHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Launch Height ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Sets the initial height of the projectile in meters. If no value is entered, this value defaults to 0m'; % adds information to description
        end

        % Button pushed function: RestitutionHelp
        function RestitutionHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Coefficient Of Restitution ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Sets the coefficient of restitution of the projectile. The value must be between 0 and 1. If no value is entered, the simulation will run under ideal circumstances with a coefficient of 1 by default. The coefficient of restitution is the ratio of the speed after collision compared to the speed before the collision. A lower coefficient of restitution means more energy is lost in the collision.'; % adds information to description
        end

        % Button pushed function: AccellerationHelp
        function AccellerationHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Downwards Accelleration ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Sets the rate of change of velocity downwards. If no value is entered, the simulation will model accelleration due to gravity at sea level on Earth (9.81ms^-1). A higher accelleration will decrease the maximum height of the projectile if launched with the same vertical magnitude.'; % adds information to description
        end

        % Button pushed function: ZAxisHelp
        function ZAxisHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Z axis toggle ]'; % sets title of help box
            app.DescriptionLabel.Text = 'Allows the user to input a z axis angle to allow the simulation to run in three dimensions. By default this is disabled.'; % adds information to description
        end

        % Button pushed function: HoldHelp
        function HoldHelpButtonPushed(app, event)
            app.ParameterLabel.Text = '[ Hold previous paths ]'; % sets title of help box
            app.DescriptionLabel.Text = 'If enabled, the previously plotted trajectories will not be cleared while another trajectory is being plotted so that they may be compared to eachother. By default this parameter is enabled.'; % adds information to description
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value; % assigns current button value to a variable
            app.HorizontalAngledegreesEditField.Enable = value; % enables the horizontal angle field
            app.View3Button.Enable = value; % enables the 3d view button
            if value == "On" % if the switch is on the following code will execute 
                app.View3Button.Value = 1; % the 3d veiw button is selected
                app.View3ButtonValueChanged(); % sets the view to 3d
                app.View1Button.Value = 0; % turns off the view 1 button 
            else % otherwise the following code will execute
                app.View3Button.Value = 0; % sets the 3d view button to off
                app.View1Button.Value = 1; % sets the view 1 button to on
                app.View1ButtonValueChanged(); % sets the view to view 1
            end % ends the if statement
        end

        % Value changed function: View1Button
        function View1ButtonValueChanged(app, event)
            value = app.View1Button.Value; % assigns current button value to a variable
            if value % if the button is on the following code will execute
                view(app.UIAxes, 0, 0); % sets the view to the side 2d
                app.View2Button.Value = 0; % turns off button 2
                app.View3Button.Value = 0; % turns off button 3
            elseif ~app.View2Button.Value && ~app.View3Button.Value % if neither button 2 or 3 are active the following code will execute
                app.View1Button.Value = 1; % sets the value of this button to 1
            end % ends if statement
        end

        % Value changed function: View2Button
        function View2ButtonValueChanged(app, event)
            value = app.View2Button.Value; % assigns current button value to a variable
            if value % if the button is on the following code will execute
                view(app.UIAxes, 0, 90); % sets the view to the top 2d
                app.View1Button.Value = 0; % turns off button 1
                app.View3Button.Value = 0; % turns off button 3
            else % otherwise the following code will execute
                app.View1Button.Value = 1; % turns on button 1
                app.View1ButtonValueChanged(); % sets the view to view 1
            end % ends if statement
        end

        % Value changed function: View3Button
        function View3ButtonValueChanged(app, event)
            value = app.View3Button.Value; % assigns current button value to a variable
            if value % if the button is on the following code will execute
                view(app.UIAxes, -45, 45); % sets the view to 3d
                app.View1Button.Value = 0; % turns off button 1
                app.View2Button.Value = 0; % turns off button 2
            else % otherwise the following code will execute
                app.View1Button.Value = 1; % turns on button 1
                app.View1ButtonValueChanged(); % sets the view to view 1 
            end % ends the if statement
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            value = app.ThemeDropDown.Value; % assigns current drop down value to a variable
            switch value % depending on the value of the variable one of the following cases will be executed. This is done as a switch to allow themes to be added easily
                case "Light" % if the light theme is selected the following code will execute
                    app.Slider.Enable = "off"; % disables the brightness slider
                    app.UIFigure.Color = [1, 0.95, 0.98]; % sets the background colour to pink
                    app.ProjectileSimulatorLabel.FontColor = [0, 0, 0]; % sets the title font colour to black
                    app.brightness = 0.98; % sets the brightness to 0.98
                    app.brightnessControl(); % calls function to change the brightness of multiple fields simultaneously
                    app.HorizontalAngledegreesLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.VerticalAngledegreesLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Velocityms1Label.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.VerticalAngledegreesLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.WalltoggleLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.DistancemLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.HeightmLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Switch.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Switch_2.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Switch_3.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.ResultantVectorLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.ComponentsLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.LaunchHeightmLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.CoefficientofrestitutionLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.DVectorToggleLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.HoldpreviouspathsLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.BrightnessLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.ParameterLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.DescriptionLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.DownwardAccellerationLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.CurrentTimesLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_2.FontColor = [0, 0, 0]; % sets the font colour to black

                    app.xCoordinate.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.yCoordinate.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.zCoordinate.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_3.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_4.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_5.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_6.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_7.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_8.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.Label_9.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.XComponentLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.YComponentLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.ZComponentLabel.FontColor = [0, 0, 0]; % sets the font colour to black
                    app.CoordinatesLabel.FontColor = [0, 0, 0]; % sets the font colour to black

                    app.UIAxes.Color = [1, 1, 1]; % sets the background colour of the axes to white
                    app.UIAxes.XColor = [0, 0, 0]; % sets the font colour to black
                    app.UIAxes.YColor = [0, 0, 0]; % sets the font colour to black
                    app.UIAxes.ZColor = [0, 0, 0]; % sets the font colour to black
                    app.UIAxes.Title.Color = [0, 0, 0]; % sets the font colour to black
                    app.UIAxes.GridColor = [0, 0, 0]; % sets the grid colour to black
                case "Dark" % if the dark theme is selected the following code will execute
                    app.Slider.Enable = "on"; % enables the brightness slider
                    app.UIFigure.Color = [0, 0, 0]; % sets the background colour to black
                    app.ProjectileSimulatorLabel.FontColor = [1, 0.95, 0.98]; % sets the title font colour to pink
                    app.brightness = 0.075; % sets the brightness to 0.075
                    app.brightnessControl(); % calls function to change the brightness of multiple fields simultaneously
                    app.HorizontalAngledegreesLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.VerticalAngledegreesLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Velocityms1Label.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.VerticalAngledegreesLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.WalltoggleLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.DistancemLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.HeightmLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Switch.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Switch_2.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Switch_3.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.ResultantVectorLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.ComponentsLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.LaunchHeightmLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.CoefficientofrestitutionLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.DVectorToggleLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.HoldpreviouspathsLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.BrightnessLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.ParameterLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.DescriptionLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.DownwardAccellerationLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.CurrentTimesLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_2.FontColor = [1, 1, 1]; % sets the font colour to white
                    
                    app.xCoordinate.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.yCoordinate.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.zCoordinate.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_3.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_4.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_5.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_6.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_7.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_8.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.Label_9.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.XComponentLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.YComponentLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.ZComponentLabel.FontColor = [1, 1, 1]; % sets the font colour to white
                    app.CoordinatesLabel.FontColor = [1, 1, 1]; % sets the font colour to white

                    app.UIAxes.Color = [0, 0, 0]; % sets the background colour of the axes to black
                    app.UIAxes.XColor = [1, 1, 1]; % sets the font colour to white
                    app.UIAxes.YColor = [1, 1, 1]; % sets the font colour to white
                    app.UIAxes.ZColor = [1, 1, 1]; % sets the font colour to white
                    app.UIAxes.Title.Color = [1, 1, 1]; % sets the font colour to white
                    app.UIAxes.GridColor = [1, 1, 1]; % sets the grid colour to black
            end % ends the switch
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value; % assigns the current value of the brightness slider to a variable
            app.brightness = changingValue; % the brightness variable is set to the slider value
            app.brightnessControl(); % the function to change the brightness is called
        end

        % Callback function
        function Velocityms1EditFieldValueChanged(app, event)

        end

        % Value changed function: UpwardsAngledegreesEditField
        function UpwardsAngledegreesEditFieldValueChanged(app, event)
            value = app.UpwardsAngledegreesEditField.Value; % assigns the current value of the field to a variable
            if value < 0 || value > 90 % if the value is less than 0 or more than 90 the following code will execute
                app.UpwardsAngledegreesEditField.Value = 0; % sets the field value to 0
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The vertical angle should be between 0 and 90 degrees inclusive.'; % adds information to description
            end % ends the if statement
        end

        % Value changed function: HorizontalAngledegreesEditField
        function HorizontalAngledegreesEditFieldValueChanged(app, event)
            value = app.HorizontalAngledegreesEditField.Value; % assigns the current value of the field to a variable
            if value < -90 || value > 90 % if the value is less than -90 or more than 90 the following code will execute
                app.HorizontalAngledegreesEditField.Value = 0; % sets the field value to 0
                app.ParameterLabel.Text = 'Error!'; % sets title of help box
                app.DescriptionLabel.Text = 'The horizontal angle should be between -90 and 90 degrees inclusive.'; % adds information to description
            end % ends the if statement
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitialVectorInputPanel = uipanel(app.UIFigure);
            app.InitialVectorInputPanel.Title = 'Initial Vector Input Panel';
            app.GridLayout = uigridlayout(app.InitialVectorInputPanel);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'no.'; 'Magnitude'; 'V. Angle'; 'H Angle'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.Velocityms1EditField = uieditfield(app.GridLayout, 'numeric');
            app.UpwardsAngledegreesEditField = uieditfield(app.GridLayout, 'numeric');
            app.UpwardsAngledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @UpwardsAngledegreesEditFieldValueChanged, true);
            app.HorizontalAngledegreesEditField = uieditfield(app.GridLayout, 'numeric');
            app.HorizontalAngledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalAngledegreesEditFieldValueChanged, true);
            app.HorizontalAngledegreesEditField.Enable = 'off';
            app.SubmitvectorButton = uibutton(app.GridLayout, 'push');
            app.SubmitvectorButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitvectorButtonPushed, true);
            app.SubmitvectorButton.Text = 'Submit vector';
            app.RemovelastvectorButton = uibutton(app.GridLayout, 'push');
            app.RemovelastvectorButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastvectorButtonPushed, true);
            app.RemovelastvectorButton.Enable = 'off';
            app.RemovelastvectorButton.Text = 'Remove last vector';
            app.Velocityms1Label = uilabel(app.GridLayout);
            app.Velocityms1Label.Text = 'Velocity / ms^-1';
            app.VerticalAngledegreesLabel = uilabel(app.GridLayout);
            app.VerticalAngledegreesLabel.Text = 'Vertical Angle / degrees';
            app.HorizontalAngledegreesLabel = uilabel(app.GridLayout);
            app.HorizontalAngledegreesLabel.Text = 'Horizontal Angle / degrees';
            app.ProjectileSimulatorLabel = uilabel(app.UIFigure);
            app.ProjectileSimulatorLabel.Text = 'Projectile Simulator';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.GridLayout6 = uigridlayout(app.GraphPanel);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'Forward Displacement / m')
            ylabel(app.UIAxes, 'Left/Right Displacement / m')
            zlabel(app.UIAxes, 'Vertical Displacement / m')
            app.PlotTrajectoryButton = uibutton(app.GridLayout6, 'push');
            app.PlotTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryButtonPushed, true);
            app.PlotTrajectoryButton.Enable = 'off';
            app.PlotTrajectoryButton.Text = 'Plot Trajectory';
            app.RealTimePanel = uipanel(app.GridLayout6);
            app.RealTimePanel.Title = 'Real Time';
            app.GridLayout2 = uigridlayout(app.RealTimePanel);
            app.RealtimeLamp = uilamp(app.GridLayout2);
            app.RealTimeHelp = uibutton(app.GridLayout2, 'push');
            app.RealTimeHelp.ButtonPushedFcn = createCallbackFcn(app, @RealTimeHelpButtonPushed, true);
            app.RealTimeHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.RealTimeHelp.Text = '';
            app.VerticalSpeedPanel = uipanel(app.GridLayout6);
            app.VerticalSpeedPanel.Title = 'Vertical Speed';
            app.GridLayout3 = uigridlayout(app.VerticalSpeedPanel);
            app.Verticalspeedms1Gauge = uigauge(app.GridLayout3, 'linear');
            app.Verticalspeedms1Gauge.Limits = [0 30];
            app.VerticalSpeedHelp = uibutton(app.GridLayout3, 'push');
            app.VerticalSpeedHelp.ButtonPushedFcn = createCallbackFcn(app, @VerticalSpeedHelpButtonPushed, true);
            app.VerticalSpeedHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.VerticalSpeedHelp.Text = '';
            app.TimesPanel = uipanel(app.GridLayout6);
            app.TimesPanel.Title = 'Time / s';
            app.GridLayout4 = uigridlayout(app.TimesPanel);
            app.TimeSlider = uislider(app.GridLayout4);
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {''};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeSlider.Enable = 'off';
            app.CurrentTimesLabel = uilabel(app.GridLayout4);
            app.CurrentTimesLabel.Text = 'Current Time / s :';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '0';
            app.TimeHelp = uibutton(app.GridLayout4, 'push');
            app.TimeHelp.ButtonPushedFcn = createCallbackFcn(app, @TimeHelpButtonPushed, true);
            app.TimeHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.TimeHelp.Text = '';
            app.ViewPanel = uipanel(app.GridLayout6);
            app.ViewPanel.Title = 'View';
            app.GridLayout5 = uigridlayout(app.ViewPanel);
            app.View3Button = uibutton(app.GridLayout5, 'state');
            app.View3Button.ValueChangedFcn = createCallbackFcn(app, @View3ButtonValueChanged, true);
            app.View3Button.Enable = 'off';
            app.View3Button.Text = '3D';
            app.View2Button = uibutton(app.GridLayout5, 'state');
            app.View2Button.ValueChangedFcn = createCallbackFcn(app, @View2ButtonValueChanged, true);
            app.View2Button.Text = 'Top';
            app.View1Button = uibutton(app.GridLayout5, 'state');
            app.View1Button.ValueChangedFcn = createCallbackFcn(app, @View1ButtonValueChanged, true);
            app.View1Button.Text = 'Side';
            app.View1Button.Value = true;
            app.ProjectileInformationPanel = uipanel(app.GridLayout6);
            app.ProjectileInformationPanel.Title = 'Projectile Information';
            app.GridLayout12 = uigridlayout(app.ProjectileInformationPanel);
            app.CoordinatesLabel = uilabel(app.GridLayout12);
            app.CoordinatesLabel.Text = 'Coordinates :';
            app.XComponentLabel = uilabel(app.GridLayout12);
            app.XComponentLabel.Text = 'X Component';
            app.YComponentLabel = uilabel(app.GridLayout12);
            app.YComponentLabel.Text = 'Y Component';
            app.ZComponentLabel = uilabel(app.GridLayout12);
            app.ZComponentLabel.Text = 'Z Component';
            app.Label_4 = uilabel(app.GridLayout12);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.GridLayout12);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.GridLayout12);
            app.Label_6.Text = '0';
            app.Label_3 = uilabel(app.GridLayout12);
            app.Label_3.Text = '(';
            app.Label_7 = uilabel(app.GridLayout12);
            app.Label_7.Text = ')';
            app.Label_8 = uilabel(app.GridLayout12);
            app.Label_8.Text = ',';
            app.Label_9 = uilabel(app.GridLayout12);
            app.Label_9.Text = ',';
            app.xCoordinate = uilabel(app.GridLayout12);
            app.xCoordinate.Text = '0';
            app.yCoordinate = uilabel(app.GridLayout12);
            app.yCoordinate.Text = '0';
            app.zCoordinate = uilabel(app.GridLayout12);
            app.zCoordinate.Text = '0';
            app.VectorResolutionPanel = uipanel(app.UIFigure);
            app.VectorResolutionPanel.Title = 'Vector Resolution Panel';
            app.GridLayout7 = uigridlayout(app.VectorResolutionPanel);
            app.UITable_2 = uitable(app.GridLayout7);
            app.UITable_2.ColumnName = {'Magnitude'; 'V. Angle'; 'H. Angle'};
            app.UITable_2.RowName = {};
            app.UITable_2.Multiselect = 'off';
            app.ResolvevectorButton = uibutton(app.GridLayout7, 'push');
            app.ResolvevectorButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvevectorButtonPushed, true);
            app.ResolvevectorButton.Text = 'Resolve vector';
            app.UITable_3 = uitable(app.GridLayout7);
            app.UITable_3.ColumnName = {'Direction'; 'Magnitude'};
            app.UITable_3.RowName = {};
            app.UITable_3.Multiselect = 'off';
            app.ResultantVectorLabel = uilabel(app.GridLayout7);
            app.ResultantVectorLabel.Text = 'Resultant Vector';
            app.ComponentsLabel = uilabel(app.GridLayout7);
            app.ComponentsLabel.Text = 'Components';
            app.AdvancedParameterControlPanel = uipanel(app.UIFigure);
            app.AdvancedParameterControlPanel.Title = 'Advanced Parameter Control Panel';
            app.GridLayout8 = uigridlayout(app.AdvancedParameterControlPanel);
            app.LaunchHeightmEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LaunchHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @LaunchHeightmEditFieldValueChanged, true);
            app.ProjectilecoefficientofrestitutionEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ProjectilecoefficientofrestitutionEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectilecoefficientofrestitutionEditFieldValueChanged, true);
            app.ProjectilecoefficientofrestitutionEditField.Value = 1;
            app.DVectorToggleLabel = uilabel(app.GridLayout8);
            app.DVectorToggleLabel.Text = '3D Vector Toggle';
            app.DownwardAccellerationEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DownwardAccellerationEditField.ValueChangedFcn = createCallbackFcn(app, @DownwardAccellerationEditFieldValueChanged, true);
            app.DownwardAccellerationEditField.Value = 9.81;
            app.Switch = uiswitch(app.GridLayout8, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.HoldpreviouspathsLabel = uilabel(app.GridLayout8);
            app.HoldpreviouspathsLabel.Text = 'Hold previous paths';
            app.Switch_3 = uiswitch(app.GridLayout8, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.CoefficientofrestitutionLabel = uilabel(app.GridLayout8);
            app.CoefficientofrestitutionLabel.Text = 'Coefficient of restitution';
            app.LaunchHeightHelp = uibutton(app.GridLayout8, 'push');
            app.LaunchHeightHelp.ButtonPushedFcn = createCallbackFcn(app, @LaunchHeightHelpButtonPushed, true);
            app.LaunchHeightHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.LaunchHeightHelp.Text = '';
            app.RestitutionHelp = uibutton(app.GridLayout8, 'push');
            app.RestitutionHelp.ButtonPushedFcn = createCallbackFcn(app, @RestitutionHelpButtonPushed, true);
            app.RestitutionHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.RestitutionHelp.Text = '';
            app.AccellerationHelp = uibutton(app.GridLayout8, 'push');
            app.AccellerationHelp.ButtonPushedFcn = createCallbackFcn(app, @AccellerationHelpButtonPushed, true);
            app.AccellerationHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.AccellerationHelp.Text = '';
            app.ZAxisHelp = uibutton(app.GridLayout8, 'push');
            app.ZAxisHelp.ButtonPushedFcn = createCallbackFcn(app, @ZAxisHelpButtonPushed, true);
            app.ZAxisHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.ZAxisHelp.Text = '';
            app.HoldHelp = uibutton(app.GridLayout8, 'push');
            app.HoldHelp.ButtonPushedFcn = createCallbackFcn(app, @HoldHelpButtonPushed, true);
            app.HoldHelp.Icon = fullfile(pathToMLAPP, 'Help.png');
            app.HoldHelp.Text = '';
            app.LaunchHeightmLabel = uilabel(app.GridLayout8);
            app.LaunchHeightmLabel.Text = 'Launch Height / m';
            app.DownwardAccellerationLabel = uilabel(app.GridLayout8);
            app.DownwardAccellerationLabel.Text = 'Downward Accelleration';
            app.WallConfigurationPanel = uipanel(app.UIFigure);
            app.WallConfigurationPanel.Title = 'Wall Configuration Panel';
            app.GridLayout10 = uigridlayout(app.WallConfigurationPanel);
            app.DistancemEditField = uieditfield(app.GridLayout10, 'numeric');
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.DistancemEditField.Value = 10;
            app.HeightmEditField = uieditfield(app.GridLayout10, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 10;
            app.WalltoggleLabel = uilabel(app.GridLayout10);
            app.WalltoggleLabel.Text = 'Wall toggle';
            app.Switch_2 = uiswitch(app.GridLayout10, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.DistancemLabel = uilabel(app.GridLayout10);
            app.DistancemLabel.Text = 'Distance / m';
            app.HeightmLabel = uilabel(app.GridLayout10);
            app.HeightmLabel.Text = 'Height / m';
            app.HelpPanel = uipanel(app.UIFigure);
            app.HelpPanel.Title = 'Help Panel';
            app.GridLayout9 = uigridlayout(app.HelpPanel);
            app.ParameterLabel = uilabel(app.GridLayout9);
            app.ParameterLabel.Text = '[Parameter]';
            app.DescriptionLabel = uilabel(app.GridLayout9);
            app.DescriptionLabel.Text = 'Click on a [?] button to display information about the corrosponding component here.';
            app.ThemePanel = uipanel(app.UIFigure);
            app.ThemePanel.Title = 'Theme';
            app.GridLayout11 = uigridlayout(app.ThemePanel);
            app.ThemeDropDown = uidropdown(app.GridLayout11);
            app.ThemeDropDown.Items = {'Light', 'Dark'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light';
            app.Slider = uislider(app.GridLayout11);
            app.Slider.Limits = [0 0.2];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 0.075;
            app.BrightnessLabel = uilabel(app.GridLayout11);
            app.BrightnessLabel.Text = 'Brightness';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectileSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end